classdef HueDashboard < matlab.apps.AppBase
    %HUEDASHBOARD (Developer and support notes)
    %   Developed by:
    %   Stephen Folmer
    %   spfolmer@niceway.com
    %   (c) 2026 Stephen Folmer
    %   
    %   Provided 'as is' without support or warranty.
    %   
    %   ----------------------------------------------------------------
    %   Developed on Matlab r2025 but, save for dark mode presentation,
    %   will be backwards compatible for several releases.
    %   ----------------------------------------------------------------
    %   
    %   Update log:
    %   20251015 - (ra4) v1.4 - corrected handling for change in Bridge IP
    %   - (ra5) v1.5 - tidied up 'no internet' handling.
    %   20251017 - (ra6) v1.6 - added detailed help text for control of
    %   lights.  
    %   20251019 - v2.0 - complete re-design of the Bridge pairing
    %   (adding to whitelist) and IP address update processes.
    %   20251022 - v2.1 cosmetic changes to informative messages.
    %   20251101 - v2.2 slightly larger display area, with new colouration,
    %   some larger fonts and real-time lights' status count after
    %   toggling.
    %   20251104 - v2.3 included dimmer switches in the sensor data table.
    %   dynamic text for low battery and unreachable lights diagnostics.
    %   20251105 - v2.4 added Last Event column to Sensors and Switches
    %   grid.  Moved things around to accommodate the expanded grid.  Also
    %   various display and performance enhancements..
    %   20251109 - v2.5 improvements to Rules details - cater for 'zero'
    %   groups and dimmers quirks.  Added 'last motion detected' section.
    %   20251112 - v2.6 added max/min temperatures in Sensor Temperatures
    %   panel title.
    %   20251121 - v2.7 added dynamic text indicating change in maximum and
    %   minimum temperatures.
    %   20251126 - v2.8 improved directory control for Hue Data.  Added
    %   HTML/CSS utilisation for enhanced presentation.
    %   20251203 - v2.9 minor bug fixes and enhancements to HTML.
    %   20251209 - v2.10 presentation improvements.
    %   20251212 - v2.11 bug fixes (including: Correct 'Lights On' count
    %   after toggling; Update base arrays after re-sorting the UI Lights
    %   grid to ensure correct lights/groups are processed); 
    %   Update the 'Lights Groups On' count in line with Toggled lights.
    %   20251224 - v3.0 added auto-restart when refresh response exceeds
    %   1.25 x app startup time.  Also incorporated hueAppDiagnostics.mat
    %   file.
    %   20251231 - v3.1 - Pulsing Hue Logo and minor code tidying.
    %   20260101 - v3.2 - removed extraneous Command Window diagnostics.
    %   20260102 - v3.3 - added count for number of Rooms with Lights ON.
    %   20260103 - v3.4 - introduced checkbox to restrict lights shown in
    %   the grid to only those that are lit and in a Room group.
    %   20260107 - v3.5 - added Location to Rules grid, which is derived by
    %   pattern-matching rules, sensors and resource links data.
    %   20260108 - v3.6 - Bri-Col column in the lights grid now shows the
    %   colour of the lights.
    %   20260110 - v3.7 - optimised hueXYtoRGB function.  Replaced arrayfun
    %   with vectorised solution.
    %   20260111 - v3.8 - remember last position of UIFigure
    %   20260113 - v3.9 - improved colour rendering for Bri-col cells.
    %   20260116 - v3.10 - convert RGB to nearest colour name match.
    %   20260117 - v4.0 - major changes.  Added 'Colour' column to Lights
    %   table.  Brightness and colour are now controlled from separate
    %   columns/cells.  Displayed lights colour names are derived directly
    %   from the Hue xy values based on nearest match to CSS colour
    %   schemes.
    %   20260119 - v4.1 - only store extracted bridge data on startup.
    %   20260122 - v4.2 - added auto-refresh option upon motion detection.
    %   20260201 - v5.0 - prepare for Bridge Pro integration.  API v2.

    % Properties that correspond to app components
    properties (Access = public)
        HueDashboardv53UIFigure        matlab.ui.Figure
        OfflineMode                    matlab.ui.control.CheckBox
        APIVersionDropDown             matlab.ui.control.DropDown
        APIVersionDropDownLabel        matlab.ui.control.Label
        AutoRefreshButton              matlab.ui.control.Button
        lightsOnRoomsOnlyCheckbox      matlab.ui.control.CheckBox
        noRoomsLightsOn                matlab.ui.control.Label
        RoomsLabel                     matlab.ui.control.Label
        inLabel                        matlab.ui.control.Label
        HueLogo                        matlab.ui.container.Panel
        ResponseLimitSwitch            matlab.ui.control.RockerSwitch
        HTMLLastMotion                 matlab.ui.control.HTML
        HTMLLightsOn                   matlab.ui.control.HTML
        HTMLTempTrend                  matlab.ui.control.HTML
        HTMLLowBatMsg                  matlab.ui.control.HTML
        WarningsPanel                  matlab.ui.container.Panel
        HTMLUnreachableLights          matlab.ui.control.HTML
        HTMLLowBatDevs                 matlab.ui.control.HTML
        LowBatteryDevicesHdr           matlab.ui.control.Label
        UnreachableLightsHdr           matlab.ui.control.Label
        onLabel                        matlab.ui.control.Label
        BridgeConfigurationandUsagePanel  matlab.ui.container.Panel
        InLabel                        matlab.ui.control.Label
        TotalDevices                   matlab.ui.control.Label
        devicesLabel                   matlab.ui.control.Label
        SwitchesLabel                  matlab.ui.control.Label
        DimmerSwitches                 matlab.ui.control.Label
        MotionSensorsLabel             matlab.ui.control.Label
        MotionSensors                  matlab.ui.control.Label
        PCSensors                      matlab.ui.control.NumericEditField
        BRSensors                      matlab.ui.control.NumericEditField
        AVSensors                      matlab.ui.control.NumericEditField
        SensorsLabel                   matlab.ui.control.Label
        PCResourceLinks                matlab.ui.control.NumericEditField
        BRResourceLinks                matlab.ui.control.NumericEditField
        AVResourceLinks                matlab.ui.control.NumericEditField
        ResourceLinksLabel             matlab.ui.control.Label
        ONSchedules                    matlab.ui.control.NumericEditField
        PCSchedules                    matlab.ui.control.NumericEditField
        BRSchedules                    matlab.ui.control.NumericEditField
        AVSchedules                    matlab.ui.control.NumericEditField
        SchedulesLabel                 matlab.ui.control.Label
        PCScenes                       matlab.ui.control.NumericEditField
        BRScenes                       matlab.ui.control.NumericEditField
        AVScenes                       matlab.ui.control.NumericEditField
        ScenesLabel                    matlab.ui.control.Label
        OnGroups                       matlab.ui.control.NumericEditField
        PCGroups                       matlab.ui.control.NumericEditField
        BRGroups                       matlab.ui.control.NumericEditField
        AVGroups                       matlab.ui.control.NumericEditField
        GroupsLabel                    matlab.ui.control.Label
        ONRules                        matlab.ui.control.NumericEditField
        PCRules                        matlab.ui.control.NumericEditField
        BRRules                        matlab.ui.control.NumericEditField
        AVRules                        matlab.ui.control.NumericEditField
        RulesLabel                     matlab.ui.control.Label
        ONLights                       matlab.ui.control.NumericEditField
        PCLights                       matlab.ui.control.NumericEditField
        BRLights                       matlab.ui.control.NumericEditField
        AVLights                       matlab.ui.control.NumericEditField
        LightsLabel                    matlab.ui.control.Label
        OnActiveLabel                  matlab.ui.control.Label
        UsedLabel                      matlab.ui.control.Label
        BridgeLimitLabel               matlab.ui.control.Label
        InstalledLabel                 matlab.ui.control.Label
        DiagnosticsTimeTextArea        matlab.ui.control.TextArea
        DiagnosticsMsgTextArea         matlab.ui.control.TextArea
        DiagnosticsTextAreaLabel       matlab.ui.control.Label
        TableSensors                   matlab.ui.control.Table
        TableLights                    matlab.ui.control.Table
        UnreachableLightsLabel         matlab.ui.control.Label
        inTimesTriggeredOrderLabel     matlab.ui.control.Label
        RulesTable                     matlab.ui.control.Table
        NeverTriggered                 matlab.ui.control.NumericEditField
        NumberofRulesNOTtriggeredsincethelastBridgefirmwareupdateLabel  matlab.ui.control.Label
        RulesLabel_2                   matlab.ui.control.Label
        LightsTable                    matlab.ui.control.Table
        AllLightsOn                    matlab.ui.control.Button
        AllLightsOff                   matlab.ui.control.Button
        ToggleALLLightsButton          matlab.ui.control.Button
        LightsOnLabel                  matlab.ui.control.Label
        TempsTable                     matlab.ui.control.Table
        SensorsandSwitchesLabel        matlab.ui.control.Label
        SensorTemperaturesPanel        matlab.ui.container.Panel
        FRSensors                      matlab.ui.control.NumericEditField
        FreezingLable                  matlab.ui.control.Label
        ColdLable                      matlab.ui.control.Label
        CoolLabel                      matlab.ui.control.Label
        WarmLabel                      matlab.ui.control.Label
        HotLabel                       matlab.ui.control.Label
        HOSensors                      matlab.ui.control.NumericEditField
        WASensors                      matlab.ui.control.NumericEditField
        COSensors                      matlab.ui.control.NumericEditField
        CLSensors                      matlab.ui.control.NumericEditField
        CMSensors                      matlab.ui.control.NumericEditField
        CMSensorsLabel                 matlab.ui.control.Label
        SWUpDateAvail                  matlab.ui.control.TextArea
        Label                          matlab.ui.control.Label
        SWversion                      matlab.ui.control.TextArea
        BridgeID                       matlab.ui.control.TextArea
        IPAddressLabel_2               matlab.ui.control.Label
        Response                       matlab.ui.control.NumericEditField
        LastBridgeDataRefreshTextArea  matlab.ui.control.TextArea
        AutoSWUpdate                   matlab.ui.control.TextArea
        LastFirmwareUpdate             matlab.ui.control.TextArea
        ZigbeeChannel                  matlab.ui.control.NumericEditField
        IPAddress                      matlab.ui.control.TextArea
        APIversion                     matlab.ui.control.TextArea
        ZigbeeChannelLabel_2           matlab.ui.control.Label
        PreviousStatusRefreshLabel     matlab.ui.control.Label
        IPAddressLabel_4               matlab.ui.control.Label
        LastFirmwareUpdateSoftwareVersionTextAreaLabel  matlab.ui.control.Label
        ZigbeeChannelLabel             matlab.ui.control.Label
        IPAddressLabel                 matlab.ui.control.Label
        IPAddressLabel_3               matlab.ui.control.Label
        RefreshButton                  matlab.ui.control.Button
        PhilipsHueDashboardLabel       matlab.ui.control.Label
        ExitButton                     matlab.ui.control.Button
        ContextMenu                    matlab.ui.container.ContextMenu
        Menu                           matlab.ui.container.Menu
    end


    properties (Access = private)
        fName
        hueIPAddress char
        appID
        dataRepository
        newBri
        cellSelIndices
        prevCellSel
        prevEventName
        editGroup
        lastKey
        xyColour
        newColr
        successfulStart
        prevMaxTemp
        prevMinTemp
        lightsOn
        tGroups
        responseLimit double = 0
        restartMsg string = ""
        diagFileName
        appFolder
        Pulse
        HTMLPulse
        roomLightsOn double = 0
        lightsOnRoomsOnly logical = false
        colBri
        startup logical = true
        UpdateTimer   timer
        lastRefresh datetime = datetime("now")
        autoRefresh logical = true
        autoRefreshCount double = 0
        sensorNameLastMovement
        vAPI double = 1 % v1 for Bridge v2 - v2 for Bridge Pro
        restoreWindowPos logical = true
        Bridge   % HueBridgeV2 instance
        idMap

        appKey % Bridge Pro appID

        allResources
        resourceMap

        offlineMode logical = false
    end


    methods (Access = private)

        function errCode = getStats(app, request)

            tic

            addpath(genpath(app.dataRepository));

            errCode = 0;

            switch request
                case 'refresh'

                    if ~ app.offlineMode
                        [errCode, app.hueIPAddress, app.appID, allLights, allRules, allSensors, allGroups, ...
                            allSchedules, allCapabilities, allResourceLinks, allScenes, config] = connectToBridge(app);
                        app.OfflineMode.Tooltip = 'In On-line Mode.';
                    else
                        dt = datetime('now', 'Format', 'dd/MM/yy HH:mm:ss');
                        strDt = string(dt);
                        app.DiagnosticsTimeTextArea.Value = strDt;
                        app.DiagnosticsMsgTextArea.Value = '** Restarted in Offline Mode **';
                        lastDiagMsg = app.DiagnosticsMsgTextArea.Value;
                        save (app.diagFileName,"lastDiagMsg");
                        [allLights, allRules, allSensors, allGroups, ...
                            allSchedules, allCapabilities, allResourceLinks, allScenes, config] ...
                            = loadAllData(app);
                        app.startup = false; % Prevent re-saving data
                        app.offlineMode = true;
                        app.successfulStart = true;
                        app.autoRefresh = false;
                        app.OfflineMode.Tooltip = 'In Off-line Mode.';
                    end

                    switch errCode
                        case 777
                            dt = datetime('now', 'Format', 'dd/MM/yy HH:mm:ss');
                            strDt = string(dt);
                            app.DiagnosticsTimeTextArea.Value = strDt;
                            app.DiagnosticsMsgTextArea.Value = '** Unable to look up the curent Hue IP address using https://discovery.meethue.com... (If using a VPN turn it off for the duration of this process **';
                            lastDiagMsg = app.DiagnosticsMsgTextArea.Value;
                            save (app.diagFileName,"lastDiagMsg");
                            [allLights, allRules, allSensors, allGroups, ...
                                allSchedules, allCapabilities, allResourceLinks, allScenes, config] ...
                                = loadAllData(app);
                            app.startup = false; % Prevent re-saving data
                            app.offlineMode = true;
                            app.successfulStart = true;
                            app.OfflineMode.Value = 1;
                            app.autoRefresh = false;
                            app.OfflineMode.Tooltip = 'In Off-line Mode.';
                        case 888
                            dt = datetime('now', 'Format', 'dd/MM/yy HH:mm:ss');
                            strDt = string(dt);
                            app.DiagnosticsTimeTextArea.Value = strDt;
                            app.DiagnosticsMsgTextArea.Value = '** Not connected to Bridge **';
                            lastDiagMsg = app.DiagnosticsMsgTextArea.Value;
                            save (app.diagFileName,"lastDiagMsg");
                            return;
                        case 999
                            dt = datetime('now', 'Format', 'dd/MM/yy HH:mm:ss');
                            strDt = string(dt);
                            app.DiagnosticsTimeTextArea.Value = strDt;
                            app.DiagnosticsMsgTextArea.Value = '** No Internet **';
                            lastDiagMsg = app.DiagnosticsMsgTextArea.Value;
                            save (app.diagFileName,"lastDiagMsg");
                            return;
                        otherwise
                    end

                case 'startup'
                    app.responseLimit = 0;
                    app.prevMaxTemp = -999;
                    app.prevMinTemp = 999;
                    if ispc
                        app.fName = [app.dataRepository '\userNameHue.mat'];
                        app.diagFileName = [app.dataRepository '\hueAppDiagnostics.mat'];
                    else
                        app.fName = [app.dataRepository '/userNameHue.mat'];
                        app.diagFileName = [app.dataRepository '/hueAppDiagnostics.mat'];
                    end
                    try
                        load(app.diagFileName, "restartDetails");
                        if ~contains(restartDetails, 'success','IgnoreCase',true)
                            app.restartMsg = restartDetails;
                        else
                            app.restartMsg = "";
                        end
                    catch
                        app.restartMsg = 'No restart details found';
                    end
                    try
                        fprintf('Checking that %s is readable...', app.fName);
                        load(app.fName, "hueUser");  % userNameHue
                        app.appID = hueUser;
                        disp(app.appID)
                        fprintf('OK. \n');
                    catch EM
                        disp(EM.message)
                        disp(EM.cause);
                        fprintf('PROBLEM...Creating temporary dummy ID...will need to pair with Bridge.\n');
                        hueUser = 'dummy-need-to-pair'; % app.appID
                        save([app.fName],"hueUser");
                    end
                    return;
                otherwise
            end

            app.cellSelIndices = [0 0];
            app.newBri = 0;
            app.prevEventName = 'none';
            app.editGroup = '';
            cd (app.dataRepository)
            % app.IPAddress.Value = config.ipaddress;


            % if ispc
            %     IPfile = [app.dataRepository '\hueIPAddress.mat'];
            % else
            %     IPfile = [app.dataRepository '/hueIPAddress.mat'];
            % end
            % app.fName = IPfile;
            % app.hueIPAddress = load(app.fName,"hueIPAddress");
            % % app.hueIPAddress = struct2cell(app.hueIPAddress);
            app.IPAddress.Value = app.hueIPAddress;

            %% >>> Amend or remove
            %%% Check for Bridge version
            %%% May need to cater for Bridge v2 and Pro working in tandem..
            %%%
            % app.vAPI = detectHueApiVersion(app); controlled by
            % app.APIVersionDropDown callback

            if app.vAPI == 1
                % whos('config')
                % isfield(config, 'ipaddress')
                % fieldnames(config)
                % class(config.ipaddress)
                % whos config.ipaddress
                % class(app)
                % whos app


                app.hueIPAddress = config.ipaddress;
                app.BridgeID.Value = config.bridgeid;
                app.ZigbeeChannel.Value = config.zigbeechannel;
                app.APIversion.Value = config.apiversion;
                if config.swupdate2.autoinstall.on
                    app.AutoSWUpdate.Value = 'On';
                else
                    app.AutoSWUpdate.Value = 'Off';
                end
                % Convert to datetime using input format
                dt = datetime(config.swupdate2.bridge.lastinstall, 'InputFormat', 'yyyy-MM-dd''T''HH:mm:ss');
                dt.Format = 'dd-MMM-yyyy HH:mm:ss';
                app.LastFirmwareUpdate.Value = string(dt);
                app.SWversion.Value = config.swversion;
                app.SWUpDateAvail.Value = config.swupdate2.bridge.state;
                if config.swupdate2.bridge.state == "noupdates"
                    app.SWUpDateAvail.Value = 'No updates available';
                else
                    if contains(config.swupdate2.bridge.state,'readytoinstall')
                        app.SWUpDateAvail.Value = 'Updates available' ;
                    else
                        app.SWUpDateAvail.Value = 'Unknown';
                    end
                end
            else
                % api v2
                % id: '0f1d347e-d834-4c48-ac30-0c269befad5b'
                % owner: [1×1 struct]
                % bridge_id: '001788fffe75a4e9'
                % time_zone: [1×1 struct]
                % type: 'bridge'
                app.idMap = buildHueIdMaps(app);
                disp(app.idMap);
                app.BridgeID.Value = config.bridge_id;
            end
            %%% <<<
            try % not found for api v2
                app.AVLights.Value = allCapabilities.lights.total - allCapabilities.lights.available;
                app.BRLights.Value = allCapabilities.lights.total;
                app.PCLights.Value = (allCapabilities.lights.total - allCapabilities.lights.available) ...
                    * 100 / allCapabilities.lights.total;
                app.AVSensors.Value = allCapabilities.sensors.total - allCapabilities.sensors.available;
                app.BRSensors.Value = allCapabilities.sensors.total;
                app.PCSensors.Value = (allCapabilities.sensors.total - allCapabilities.sensors.available) ...
                    * 100 / allCapabilities.sensors.total;
                app.AVScenes.Value = allCapabilities.scenes.total - allCapabilities.scenes.available;
                app.BRScenes.Value = allCapabilities.scenes.total;
                app.PCScenes.Value = (allCapabilities.scenes.total - allCapabilities.scenes.available) ...
                    * 100 / allCapabilities.scenes.total;
                app.AVSchedules.Value = allCapabilities.schedules.total - allCapabilities.schedules.available;
                app.BRSchedules.Value = allCapabilities.schedules.total;
                app.PCSchedules.Value = (allCapabilities.schedules.total - allCapabilities.schedules.available) ...
                    * 100 / allCapabilities.schedules.total;
                app.AVRules.Value = allCapabilities.rules.total - allCapabilities.rules.available;
                app.BRRules.Value = allCapabilities.rules.total;
                app.PCRules.Value = (allCapabilities.rules.total - allCapabilities.rules.available) ...
                    * 100 / allCapabilities.rules.total;
                app.AVResourceLinks.Value = allCapabilities.resourcelinks.total - allCapabilities.resourcelinks.available;
                app.BRResourceLinks.Value = allCapabilities.resourcelinks.total;
                app.PCResourceLinks.Value = (allCapabilities.resourcelinks.total - allCapabilities.resourcelinks.available) ...
                    * 100 / allCapabilities.resourcelinks.total;
            catch

            end
            %%%%
            %%%%  Sensors
            %%%%

            tableSensors = struct2table(allSensors);
            noSensors = size(tableSensors);
            fieldX = fieldnames(allSensors(1,1));
            % Create a standard format table of Sensors
            sz = [noSensors(1,2) 9];
            varTypes = ["double", "string", "string", "string", "string", "double", "double","datetime","string"];
            varNames = ["sensorno", "sensorname", "modelID", "productname", "type", "battery", "temp","lastevent","uniqueid"];
            tSensors = table('Size',sz,'VariableTypes',varTypes,'VariableNames',varNames);
            tSensors.sensorno = str2double(extractAfter(fieldX, 'x'));
            lowBattery = 0;
            maxTemp = -99;
            maxRoom = [];
            minTemp = 99;
            minRoom = [];
            warm = 0;
            veryWarm = 0;
            cool = 0;
            cold = 0;
            freezing = 0;
            tgt = 0;
            noTemps = 0;
            noMotion = 0;
            noDimmers = 0;
            for i = 1:noSensors(1,2)
                try
                    battery = tableSensors.(fieldX{i,1}).config.battery;
                    tSensors.battery(i) = tableSensors.(fieldX{i,1}).config.battery;
                    tSensors.productname(i) = tableSensors.(fieldX{i,1}).productname;
                    tSensors.modelID(i) = tableSensors.(fieldX{i,1}).modelid;
                    tSensors.uniqueid(i) = tableSensors.(fieldX{i,1}).uniqueid;
                    try
                        tSensors.lastevent(i) = tableSensors.(fieldX{i,1}).state.lastupdated;
                    catch
                        tSensors.lastevent(i) = tableSensors.(fieldX{i,1}).swupdate.lastinstall;
                    end
                    if contains(tSensors.productname(i),'motion')
                        noMotion = noMotion + 1;
                        if battery < 30
                            lowBattery = lowBattery + 1;
                        end
                    end
                    if contains(tSensors.productname(i),'dimmer')
                        noDimmers = noDimmers + 1;
                        if battery < 30
                            lowBattery = lowBattery + 1;
                        end
                    end
                catch
                end
                tSensors.sensorname(i) = tableSensors.(fieldX{i,1}).name;
                tSensors.type(i) = tableSensors.(fieldX{i,1}).type;
                if  contains(tSensors.type(i) ,"Temperature")
                    tSensors.temp(i) = tableSensors.(fieldX{i,1}).state.temperature/100;
                    noTemps = noTemps + 1;
                    if tSensors.temp(i) > maxTemp
                        maxTemp = tSensors.temp(i);
                        maxRoom = extractBetween(tSensors.sensorname(i),'Sensor','Temp');
                    end
                    if tSensors.temp(i) < minTemp
                        minTemp = tSensors.temp(i);
                        minRoom = extractBetween(tSensors.sensorname(i),'Sensor','Temp');
                    end
                    if tSensors.temp(i) > 30
                        veryWarm = veryWarm + 1;
                    elseif tSensors.temp(i) > 21
                        warm = warm + 1;
                    elseif tSensors.temp(i) < 0
                        freezing = freezing + 1;
                    elseif tSensors.temp(i) < 10
                        cold = cold + 1;
                    elseif tSensors.temp(i) < 18
                        cool = cool + 1;
                    else
                        tgt = tgt + 1;
                    end
                end
            end

            app.WASensors.Value = warm;
            app.HOSensors.Value = veryWarm;
            app.CLSensors.Value = cool;
            app.COSensors.Value = cold;
            app.FRSensors.Value = freezing;
            app.CMSensors.Value = tgt;
            if app.prevMaxTemp == -999
                app.prevMaxTemp = maxTemp;
            end
            if app.prevMinTemp == 999
                app.prevMinTemp = minTemp;
            end
            if maxTemp > app.prevMaxTemp
                maxTempText = append('<font color="yellow">Max: </font>', maxRoom,...
                    '<font color="yellow">',' RISING',' </font>', ' to ', num2str(maxTemp,'%+2.2f'));
                app.prevMaxTemp = maxTemp;
            elseif maxTemp < app.prevMaxTemp
                maxTempText = append('<font color="yellow">Max: </font>', maxRoom, ...
                    '<font color="cyan">',' DROPPING',' </font>', ' to ', num2str(maxTemp,'%+2.2f'));
                app.prevMaxTemp = maxTemp;
            else
                maxTempText = append('<font color="yellow">Max: </font>', maxRoom, ...
                    '<font color="grey">',' STABLE',' </font>', ' at ', num2str(maxTemp,'%+2.2f'));
            end

            if minTemp > app.prevMinTemp
                minTempText = append('<font color="yellow">Min: </font>', minRoom, ...
                    '<font color="yellow">',' RISING',' </font>', ' to ', num2str(minTemp,'%+2.2f'));
                app.prevMinTemp = minTemp;
            elseif minTemp < app.prevMinTemp
                minTempText = append('<font color="yellow">Min: </font>', minRoom, ...
                    '<font color="cyan">',' DROPPING',' </font>', ' to ', num2str(minTemp,'%+2.2f'));
                app.prevMinTemp = minTemp;
            else
                minTempText = append('<font color="yellow">Min: </font>', minRoom, ...
                    '<font color="grey">',' STABLE',' </font>', ' at ', num2str(minTemp,'%+2.2f'));
            end

            app.SensorTemperaturesPanel.Title = append('Sensor Temperatures           Max ',...
                num2str(maxTemp,'%+2.2f'), ' <-> Min ', num2str(minTemp,'%+2.2f'));
            app.MotionSensors.Text = num2str(noMotion);
            app.DimmerSwitches.Text = num2str(noDimmers);
            app.TotalDevices.Text =num2str(noMotion + noDimmers);
            noTempsnDimmers = noTemps + noDimmers;
            sz = [noTempsnDimmers 4];
            varTypes = ["string", "double", "string","datetime"];
            varNames = ["temp", "battery", "sensorname","lastevent"];
            tTemps = table('Size',sz,'VariableTypes',varTypes,'VariableNames',varNames);
            j = 0;
            lastMotionIdx = 0;
            for i = 1:noSensors(1,2)
                if  contains(tSensors.type(i) ,"Temperature")
                    j = j + 1;
                    tTemps.temp(j) = num2str(tSensors.temp(i),'%+2.2f');
                    tTemps.battery(j) = tSensors.battery(i);
                    tTemps.sensorname(j) = strcat(tSensors.sensorname(i), ' (', tSensors.modelID(i), ')');
                    tTemps.lastevent(j) = tSensors.lastevent(i);
                elseif contains(tSensors.type(i) ,"Switch")
                    j = j+ 1;
                    tTemps.temp(j) = ' ';
                    tTemps.battery(j) = tSensors.battery(i);
                    tTemps.sensorname(j) = strcat(tSensors.sensorname(i), ' (', tSensors.modelID(i), ')');
                    tTemps.lastevent(j) = tSensors.lastevent(i);
                elseif contains(tSensors.type(i) ,"Presence")
                    if lastMotionIdx == 0 || tSensors.lastevent(i) > lastMotion
                        lastMotion = tSensors.lastevent(i);
                        lastMotionIdx = i;
                    end
                end
            end

            if lastMotionIdx > 0
                app.sensorNameLastMovement = tSensors.sensorname(lastMotionIdx);
                motionDate = extractBefore(string(lastMotion), " ");
                motionTime = extractAfter(string(lastMotion), " ");
                lastMotionText  = append('Last Motion detected by ', tSensors.sensorname(lastMotionIdx),...
                    newline, 'on ', motionDate, ' at ', motionTime);
                if strlength(tSensors.sensorname(lastMotionIdx)) > 25
                    lastMotionFontsize = '16px';
                else
                    lastMotionFontsize = '18px';
                end
                lastMotionFontcol = 'cyan';
                lastMotionFontweight = 'bold';
            else
                lastMotionText = 'No Motions detected.';
                lastMotionFontsize = 14;
                lastMotionFontcol = 'yellow';
                lastMotionFontweight= 'normal';
            end
            app.HTMLLastMotion.Data = struct( ...
                'message', lastMotionText, ...
                'color', lastMotionFontcol, ...
                'fontsize', lastMotionFontsize, ...
                'fontweight', lastMotionFontweight, ...
                'lineheight', '0.75', ...
                'width', '385px', ...
                'height', '37px', ...
                'speed', '10s');

            tTemps = sortrows(tTemps,{'temp','sensorname'},{'descend', 'ascend'});
            app.TempsTable.Data = tTemps;

            app.SensorsandSwitchesLabel.Text = '<html><p style="line-height:0.75;">Sensors and<br>Switches</p></html>';

            if lowBattery > 0
                sz = [lowBattery 3];
                varTypes = ["double", "string", "string"];
                varNames = ["battery", "modelID", "sensorname"];
                tLowBat = table('Size',sz,'VariableTypes',varTypes,'VariableNames',varNames);
                j = 0;
                for i = 1:noSensors(1,2)
                    try
                        battery = tableSensors.(fieldX{i,1}).config.battery;
                        if (contains(tSensors.productname(i),'motion') ...
                                || contains(tSensors.productname(i),'dimmer')) ...
                                && battery < 30
                            j = j + 1;
                            tLowBat.battery(j) = battery;
                            tLowBat.modelID(j) = tableSensors.(fieldX{i,1}).modelid;
                            tLowBat.sensorname(j) = tableSensors.(fieldX{i,1}).name;
                        end
                    catch
                    end
                end
                app.TableSensors.Data = sortrows(tLowBat);
                s1 = uistyle('BackgroundColor','red');
                s2 = uistyle;
                s2.FontColor = 'black';
                addStyle(app.TableSensors,s1,'column',1);
                addStyle(app.TableSensors,s2,'column',1);
                app.TableSensors.Visible = "on";
                app.HTMLLowBatMsg.Visible = "on";
                % Fast blink (1 second per cycle)
                app.HTMLLowBatDevs.Data = struct( ...
                    'message', num2str(lowBattery), ...
                    'color', 'red', ...
                    'fontfamily', 'Arial Black', ...
                    'fontsize', '16px', ...
                    'fontweight', 'bold', ...
                    'lineheight', '0', ...
                    'width', '45px', ...
                    'height', '25px', ...
                    'speed', '1s');
                % pulseText.html..(medium cycle)
                app.HTMLLowBatMsg.Data = struct( ...
                    'message', '⚠ Devices with a Battery Level below 30% ⚠', ...
                    'speed', '5s');
            else
                % No blinking
                app.HTMLLowBatDevs.Data = struct( ...
                    'message', 'none', ...
                    'color', 'white', ...
                    'fontfamily', 'Calibri', ...
                    'fontsize', '14px', ...
                    'fontweight', 'normal', ...
                    'lineheight', '0', ...
                    'width', '45px', ...
                    'height', '25px', ...
                    'speed', '0s');
                app.TableSensors.Visible = "off";
                app.HTMLLowBatMsg.Visible = "on";
                % pulseText.html..(very slow cycle)
                app.HTMLLowBatMsg.Data = struct( ...
                    'message', '✔ All devices have a Battery Level above 30% ✔', ...
                    'color', 'green', ...
                    'speed', '10s');
            end

            %%%%
            %%%% Lights
            %%%%

            tableLights = struct2table(allLights);
            noLights = size(tableLights);
            fieldX = fieldnames(allLights(1,1));

            % Create a standard format table of Lights
            sz = [noLights(1,2) 12];
            varTypes = ["double", "string", "string", "string","string","double","double","double","logical","string","logical","string"];
            varNames = ["lightno", "lightname", "productname", "model", "type","bright","x","y","on","uniqueid","reachable","colormode"];
            tLights = table('Size',sz,'VariableTypes',varTypes,'VariableNames',varNames);
            tLights.lightno = str2double(extractAfter(fieldX, 'x'));
            lightsReachable = 0;
            app.lightsOn = 0;

            for i = 1:noLights(1,2)
                tLights.model(i) = tableLights.(fieldX{i,1}).modelid;
                tLights.productname(i) = tableLights.(fieldX{i,1}).productname;
                tLights.lightname(i) = tableLights.(fieldX{i,1}).name;
                tLights.type(i) = tableLights.(fieldX{i,1}).type;
                tLights.reachable(i) = tableLights.(fieldX{i,1}).state.reachable;
                tLights.uniqueid(i) = tableLights.(fieldX{i,1}).uniqueid;
                tLights.bright(i) = tableLights.(fieldX{i,1}).state.bri;
                try
                    tLights.colormode(i) = tableLights.(fieldX{i,1}).state.colormode;
                catch
                    tLights.colormode(i) = "white";
                end
                % xy colours..
                if tLights.colormode(i) == "xy" || tLights.colormode(i) == "hs"
                    tLights.x(i) = tableLights.(fieldX{i,1}).state.xy(1,1);
                    tLights.y(i) = tableLights.(fieldX{i,1}).state.xy(2,1);
                else % not a colour bulb so default to cool white
                    tLights.colormode(i) = "white";
                    tLights.x(i) = 0.3127;
                    tLights.y(i) = 0.3290;
                end
                if tLights.reachable(i)
                    lightsReachable = lightsReachable + 1;
                end
                tLights.on(i) = tableLights.(fieldX{i,1}).state.on;
                if tLights.on(i)
                    app.lightsOn = app.lightsOn + 1;
                end
            end

            unreachable = noLights(1,2) - lightsReachable;
            app.ONLights.Value = app.lightsOn;
            if app.lightsOn == 1
                app.LightsOnLabel.Text = 'Light';
            else
                app.LightsOnLabel.Text = 'Lights';
            end
            blinkLightsOn(app);

            if unreachable > 0
                sz = [unreachable 2];
                varTypes = ["string", "string"];
                varNames = ["ID", "lightname"];
                tUnreachable = table('Size',sz,'VariableTypes',varTypes,'VariableNames',varNames);
                j = 0;
                for i = 1:noLights(1,2)
                    if ~ tLights.reachable(i)
                        j = j + 1;
                        tUnreachable.ID(j) = tLights.model(i);
                        tUnreachable.lightname(j) =  tLights.lightname(i);
                    end
                end

                app.TableLights.Data = tUnreachable;
                s1 = uistyle('BackgroundColor','white');
                s2 = uistyle;
                s2.FontColor = 'black';
                s2.FontWeight = 'bold';
                addStyle(app.TableLights,s1,'column',1);
                addStyle(app.TableLights,s2,'column',1);
                app.TableLights.Visible = "on";
                app.HTMLTempTrend.Visible = "on";
                app.UnreachableLightsLabel.Interpreter = 'none';
                app.UnreachableLightsLabel.Tooltip =  'This section is only presented when there ARE Unreachable Lights.';
                % Fast blink (1 second per cycle)
                app.HTMLUnreachableLights.Data = struct( ...
                    'message', num2str(unreachable), ...
                    'color', 'red', ...
                    'fontfamily', 'Arial Black', ...
                    'fontsize', '16px', ...
                    'fontweight', 'bold', ...
                    'lineheight', '0', ...
                    'width', '45px', ...
                    'height', '25px', ...
                    'speed', '1s');
                % Fast blink (1 second per cycle)
                app.HTMLTempTrend.Data = struct( ...
                    'message', '⚠ Unreachable Lights ⚠', ...
                    'color', 'red', ...
                    'fontsize', '16px', ...
                    'fontweight', 'bold', ...
                    'lineheight', '0', ...
                    'width', '319px', ...
                    'height', '28px', ...
                    'speed', '1s');
            else
                app.TableLights.Visible = "off";
                app.HTMLTempTrend.Visible = "on";
                app.UnreachableLightsLabel.FontColor = "white";
                app.UnreachableLightsLabel.Tooltip = 'This section is only presented when there are NO Unreachable Lights.';
                app.UnreachableLightsLabel.BackgroundColor = "none";
                app.UnreachableLightsLabel.FontWeight = "bold";
                app.UnreachableLightsLabel.FontSize = 16;
                app.UnreachableLightsLabel.HorizontalAlignment = 'left';
                app.UnreachableLightsLabel.Interpreter = 'html';
                app.UnreachableLightsLabel.Text  = ...
                    append('<html><p style="line-height:1;"><font color="white"><b><br>', ...
                    maxTempText, '<br><br>', ...
                    minTempText, '</b></font></p>');
                % No blinking
                app.HTMLUnreachableLights.Data = struct( ...
                    'message', 'none', ...
                    'color', 'white', ...
                    'fontfamily', 'Calibri', ...
                    'fontsize', '14px', ...
                    'fontweight', 'normal', ...
                    'lineheight', '0', ...
                    'width', '45px', ...
                    'height', '25px', ...
                    'speed', '0s');
                % Very slow blink (10 seconds per cycle)
                app.HTMLTempTrend.Data = struct( ...
                    'message', '℃ Temperature trends since last Refresh ℃', ...
                    'color', 'white', ...
                    'fontsize', '16px', ...
                    'fontweight', 'bold', ...
                    'lineheight', '0', ...
                    'width', '319px', ...
                    'height', '28px', ...
                    'speed', '10s');
            end

            %%%%
            %%%% Schedules
            %%%%

            tableSchedules = struct2table(allSchedules);
            noSchedules = size(tableSchedules);
            fieldX = fieldnames(allSchedules(1,1));

            % Create a standard format table of Schedules
            sz = [noSchedules(1,2) 6];
            varTypes = ["double", "string", "string","string","datetime","datetime"];
            varNames = ["scheduleno", "schedulename", "desc", "status","created","starttime"];
            tSchedules = table('Size',sz,'VariableTypes',varTypes,'VariableNames',varNames);
            tSchedules.scheduleno = str2double(extractAfter(fieldX, 'x'));
            enabledSchedules = 0;
            for i = 1:noSchedules(1,2)
                tSchedules.desc(i) = tableSchedules.(fieldX{i,1}).description;
                status = tableSchedules.(fieldX{i,1}).status;
                if status == "enabled"
                    enabledSchedules = enabledSchedules + 1;
                end
                tSchedules.status(i) = tableSchedules.(fieldX{i,1}).status;
                tSchedules.schedulename(i) = tableSchedules.(fieldX{i,1}).name;
                tSchedules.created(i) = tableSchedules.(fieldX{i,1}).created;
                try
                    tSchedules.starttime(i) = tableSchedules.(fieldX{i,1}).starttime;
                catch
                end
            end
            app.ONSchedules.Value = enabledSchedules;

            %%%%
            %%%% Groups
            %%%%

            tableGroups = struct2table(allGroups);
            noGroups = size(tableGroups);
            fieldX = fieldnames(allGroups(1,1));

            app.AVGroups.Value = noGroups(1,2); % the actual number of groups in use rather than the value from allCapabilities
            app.BRGroups.Value = allCapabilities.groups.total;
            app.PCGroups.Value =  app.AVGroups.Value * 100 / app.BRGroups.Value;

            % Create a standard format table of Groups
            sz = [noGroups(1,2) 8];
            varTypes = ["double", "string", "string", "double", "string","string","logical","logical"];
            varNames = ["groupno", "groupname", "type", "nolights","lights","class","allon","anyon"];
            app.tGroups = table('Size',sz,'VariableTypes',varTypes,'VariableNames',varNames);
            app.tGroups.groupno = str2double(extractAfter(fieldX, 'x'));
            noGroupLights = 0;
            grplightsOn = 0;
            app.roomLightsOn = 0;
            for i = 1:noGroups(1,2)
                try
                    app.tGroups.lights(i) = strjoin(tableGroups.(fieldX{i,1}).lights, ', ');
                    x = size(tableGroups.(fieldX{i,1}).lights);
                    app.tGroups.nolights(i) = x(1,1);
                    noGroupLights = noGroupLights + x(1,1);
                catch
                    app.tGroups.lights(i) = "none";
                end
                app.tGroups.type(i) = tableGroups.(fieldX{i,1}).type;
                app.tGroups.groupname(i) = tableGroups.(fieldX{i,1}).name;
                app.tGroups.class(i) = tableGroups.(fieldX{i,1}).class;
                app.tGroups.allon(i) = tableGroups.(fieldX{i,1}).state.all_on;
                app.tGroups.anyon(i) = tableGroups.(fieldX{i,1}).state.any_on;
                if app.tGroups.anyon(i)
                    grplightsOn = grplightsOn + 1;
                    if app.tGroups.type(i) == "Room"
                        app.roomLightsOn = app.roomLightsOn + 1;
                    end
                end
            end

            % Create an intersection table of Groups and Lights
            sz = [noGroupLights 4];
            varTypes = ["double", "string","string","double"];
            varNames = ["groupno", "groupname", "grouptype", "lightno"];
            itGroupslights = table('Size',sz,'VariableTypes',varTypes,'VariableNames',varNames);
            l = 0;
            for i = 1:noGroups(1,2)
                if app.tGroups.nolights(i,1) > 0
                    for j = 1:app.tGroups.nolights(i)
                        l = l + 1;
                        itGroupslights.groupno(l) = app.tGroups.groupno(i,1);
                        itGroupslights.groupname(l) = app.tGroups.groupname(i,1);
                        itGroupslights.grouptype(l) = app.tGroups.type(i,1);
                        itGroupslights.lightno(l) =  str2double(tableGroups.(fieldX{i,1}).lights{j,1});
                    end
                end
            end

            tGroupsLights = join(itGroupslights, tLights);

            app.OnGroups.Value = grplightsOn;
            app.noRoomsLightsOn.Text = num2str(app.roomLightsOn);
            if app.roomLightsOn == 1
                app.RoomsLabel.Text = 'Room';
            else
                app.RoomsLabel.Text = 'Rooms';
            end

            tGroupsLights = sortrows(tGroupsLights,[10 11 2],["descend" "descend" "ascend"]);

            sz = [noGroupLights 12];
            varTypes = ["string", "string", "double","string","string","string","logical","double","string","double","double","string"];
            varNames = ["groupname", "grouptype", "lightno","lightname","productname","model","state","bright","colour","x","y","colormode"];
            allLightsTable = table('Size',sz,'VariableTypes',varTypes,'VariableNames',varNames);
            sz = [app.roomLightsOn 12];
            roomLightsTable = table('Size',sz,'VariableTypes',varTypes,'VariableNames',varNames);
            allLightsTable.groupname = tGroupsLights.groupname;
            allLightsTable.grouptype = tGroupsLights.grouptype;
            allLightsTable.lightno = tGroupsLights.lightno;
            allLightsTable.lightname = tGroupsLights.lightname;
            allLightsTable.productname = tGroupsLights.productname;
            allLightsTable.model = tGroupsLights.model;
            allLightsTable.state = tGroupsLights.on;
            allLightsTable.colour(:) = "white";
            allLightsTable.bright = max(round(tGroupsLights.bright/2.54),1);
            allLightsTable.x = tGroupsLights.x;
            allLightsTable.y = tGroupsLights.y;
            allLightsTable.colormode = tGroupsLights.colormode;

            j = 0;
            for i = 1:noGroupLights
                if allLightsTable.grouptype(i) == "Room" && allLightsTable.state(i) == true
                    j = j + 1;
                    roomLightsTable(j,:) = allLightsTable(i,:);
                end
            end
            if app.lightsOnRoomsOnly
                roomLightsTable = sortrows(roomLightsTable);
                app.LightsTable.Data = roomLightsTable(:,1:9);
            else
                allLightsTable = sortrows(allLightsTable);
                app.LightsTable.Data = allLightsTable(:,1:9);
            end
            lightsInGrid = size(app.LightsTable.Data);
            if app.lightsOnRoomsOnly
                for i=1:lightsInGrid(1,1)
                    if roomLightsTable.colormode(i) ~= "white"
                        x = roomLightsTable.x(i);
                        y = roomLightsTable.y(i);
                        bri = max(round(roomLightsTable.bright(i) * 2.54),1);
                        rgb = hueXYtoRGB(app,x,y,bri);
                        app.LightsTable.Data{i,9} = {nearestCssColourName(app, rgb)};
                        s1 = uistyle('BackgroundColor',rgb);
                        s2 = uistyle;
                        addStyle(app.LightsTable,s1,'cell', [i 9]);
                        if sum(rgb) == 0
                            s2.FontColor = 'white';
                        else
                            s2.FontColor = 'black';
                        end
                        addStyle(app.LightsTable,s2,'cell', [i 8]);
                    else
                        s1 = uistyle('BackgroundColor','white');
                        addStyle(app.LightsTable,s1,'cell', [i 9]);
                    end
                end
            else
                for i = 1:lightsInGrid(1,1)
                    if allLightsTable.colormode(i) ~= "white"
                        x = allLightsTable.x(i);
                        y = allLightsTable.y(i);
                        bri = max(round(allLightsTable.bright(i) * 2.54),1);
                        rgb = hueXYtoRGB(app,x,y,bri);
                        app.LightsTable.Data{i,9} = {nearestCssColourName(app, rgb)};
                        s1 = uistyle('BackgroundColor',rgb);
                        s2 = uistyle;
                        addStyle(app.LightsTable,s1,'cell', [i 9]);
                        if sum(rgb) == 0
                            s2.FontColor = 'white';
                        else
                            s2.FontColor = 'black';
                        end
                        addStyle(app.LightsTable,s2,'cell',[i 8]);
                    else
                        s1 = uistyle('BackgroundColor','white');
                        addStyle(app.LightsTable,s1,'cell',[i 9]);
                    end
                end
            end

            highlightOnLights(app);

            %%%%
            %%%% Scenes
            %%%%

            tableScenes = struct2table(allScenes);
            noScenes = size(tableScenes);
            fieldX = fieldnames(allScenes(1,1));

            % Create a standard format table of Scenes
            sz = [noScenes(1,2) 6];
            varTypes = ["string", "string", "string", "datetime", "string","string"];
            varNames = ["sceneID", "scenename", "type", "lastupdate", "group", "lights"];
            tScenes = table('Size',sz,'VariableTypes',varTypes,'VariableNames',varNames);
            tScenes.sceneID = replace(fieldX, '_', '-');

            for i = 1:noScenes(1,2)
                tScenes.scenename(i) = tableScenes.(fieldX{i,1}).name;
                tScenes.type(i) = tableScenes.(fieldX{i,1}).type;
                tScenes.lastupdate(i) = tableScenes.(fieldX{i,1}).lastupdated;
                try
                    tScenes.group(i) = tableScenes.(fieldX{i,1}).group;
                catch
                    tScenes.group(i) = 'n/a';
                end
                try
                    tScenes.lights(i) = strjoin(tableScenes.(fieldX{i,1}).lights, ', ');
                catch
                    tScenes.lights(i) = 'none';
                end
            end

            %%%%
            %%%% Rules
            %%%%

            tableRules = struct2table(allRules);
            noRules = size(tableRules);

            fieldX = fieldnames(allRules(1,1));

            % Create a standard format table of Rules
            sz = [noRules(1,2) 19];
            varTypes = ["double", "string", "string", "datetime", "datetime", "double", "string","string", ...
                "string", "double", "string", "string", ...
                "double", "string", ...
                "double", "string", "double", "string", "string"];
            varNames = ["ruleno", "location", "rulename", "created", "lasttriggered", "timestriggered", "status", "action", ...
                "target", "id", "description", "RL", ...
                "groupno", "groupdesc", ...
                "sensorno", "sensordesc","scheduleno", "scheduledesc","scenedesc"];
            tRules = table('Size',sz,'VariableTypes',varTypes,'VariableNames',varNames);
            tRules.ruleno = str2double(extractAfter(fieldX, 'x'));
            rulesEnabled = 0;
            rulesNotTrig = 0;

            for i = 1:noRules(1,2)
                tRules.created(i) = tableRules.(fieldX{i,1}).created;
                if tableRules.(fieldX{i,1}).lasttriggered ~= "none"
                    tRules.lasttriggered(i) = tableRules.(fieldX{i,1}).lasttriggered;
                else
                    tRules.lasttriggered(i) = tableRules.(fieldX{i,1}).created; % default to date created
                end
                tRules.timestriggered(i) = tableRules.(fieldX{i,1}).timestriggered;
                if tRules.timestriggered(i) == 0
                    rulesNotTrig = rulesNotTrig + 1;
                end
                status = tableRules.(fieldX{i,1}).status;
                if status == "enabled"
                    rulesEnabled = rulesEnabled + 1;
                end
                tRules.status(i) = tableRules.(fieldX{i,1}).status;
                tRules.action(i) = tableRules.(fieldX{i,1}).actions.address;
                tRules.rulename(i) = tableRules.(fieldX{i,1}).name;
                tRules.groupno(i)  = 0;
                tRules.sensorno(i)  = 0;
                tRules.scheduleno(i) = 0;

                grF = count(tRules.action(i),"groups/");
                grFx = count(tRules.rulename(i),"Group");
                scF = count(tRules.action(i),"scenes/");
                seF = count(tRules.action(i),"sensors/");
                seFx = count(tRules.rulename(i),"Sensor");
                diF = count(tRules.rulename(i),"Dimmer");
                shF = count(tRules.action(i),"schedules/");
                xfdF = count(tRules.rulename(i),"XFD");

                %
                % Default in case no better Description extracted in following section
                %
                if seF > 0
                    tRules.sensorno(i)  = extractBetween(tRules.action(i),"sensors/","/");
                end

                if grF > 0
                    tRules.groupno(i) = extractBetween(tRules.action(i),"groups/","/action");
                    if tRules.groupno(i) == 0
                        if seFx > 0
                            tRules.sensorno(i)  = extractBetween(tRules.rulename(i),"Sensor ",".");
                        elseif diF > 0
                            tRules.sensorno(i)  = extractBetween(tRules.rulename(i),"Dimmer","Btn");
                        end
                    end
                elseif grFx > 0
                    tRules.groupno(i) = extractAfter(tRules.rulename(i),"Group");
                elseif scF > 0
                    if seFx > 0
                        tRules.sensorno(i)  = extractBetween(tRules.rulename(i),"Sensor ",".");
                    end
                elseif seF > 0
                    if seFx > 0
                        tRules.sensorno(i)  = extractBetween(tRules.rulename(i),"Sensor ",".");
                    elseif diF > 0
                        if contains(tRules.rulename(i),"Switch")
                            tRules.sensorno(i)  = extractBetween(tRules.rulename(i),"Switch "," ");
                        else
                            tRules.sensorno(i)  = extractBetween(tRules.rulename(i),"Dimmer ",".");
                        end
                    elseif xfdF > 0
                        if contains(tRules.rulename(i),"XFDMotion")
                            try
                                tRules.sensorno(i)  = extractBetween(tRules.rulename(i),"XFDMotion",".");
                            catch
                                tRules.sensorno(i)  = extractAfter(tRules.rulename(i),"XFDMotion");
                            end
                        end
                    end
                elseif shF > 0
                    tRules.scheduleno(i)  = extractAfter(tRules.action(i),"schedules/");
                    if contains(tRules.rulename(i),"Dimmer Switch")
                        tRules.sensorno(i)  = extractBetween(tRules.rulename(i),"Switch "," ");
                    end
                end
            end

            %%%%
            %%%% Resource Links
            %%%%

            tableResourceLinks = struct2table(allResourceLinks);
            noRLs = size(tableResourceLinks);
            fieldX = fieldnames(allResourceLinks(1,1));

            % Create a standard format table of Resource Links
            sz = [noRLs(1,2) 4];
            varTypes = ["string", "string", "string", "string"];
            varNames = ["rlId", "rlname", "desc", "links"];
            tRlinks = table('Size',sz,'VariableTypes',varTypes,'VariableNames',varNames);
            tRlinks.rlId = fieldX;

            for i = 1:noRLs(1,2)
                tRlinks.rlname(i) = tableResourceLinks.(fieldX{i,1}).name;
                tRlinks.desc(i) = tableResourceLinks.(fieldX{i,1}).description;
                tRlinks.links(i) = strjoin(tableResourceLinks.(fieldX{i,1}).links, ', ');
                tRlinks.links(i) = strcat(tRlinks.links(i), ','); % add a final delimiter
            end

            %%%
            %%% Expand Rules table with Groups, Sensors, Schedules, Scenes & Resource Links data
            %%%

            for i = 1:noRules(1,2)

                tRules.RL(i) = '*';
                rlMatch = ['/rules/' num2str(tRules.ruleno(i)) ','];
                for n = 1:noRLs(1,2)
                    if contains(tRlinks.links(n), rlMatch)
                        tRules.RL(i) = tRlinks.desc(n);
                        break;
                    end
                end

                nxt = false;
                for j= 1:noGroups(1,2)
                    if tRules.groupno(i) > 0 && tRules.groupno(i) == app.tGroups.groupno(j)
                        tRules.groupdesc(i) = app.tGroups.groupname(j);
                        tRules.target(i) = "Group";
                        tRules.id(i) = app.tGroups.groupno(j);
                        tRules.description(i) = app.tGroups.groupname(j);
                        tRules.location(i) = app.tGroups.groupname(j);
                        tRules.sensordesc(i) = '*';
                        tRules.scheduledesc(i) = '*';
                        tRules.scenedesc(i) = '*';
                        nxt = true;
                        break; % only need one match.. (optimisation)
                    end
                end
                if nxt
                    continue; % process next rule.. (optimisation)
                end
                for k = 1:noSensors(1,2)
                    if tRules.sensorno(i) == tSensors.sensorno(k)
                        tRules.sensordesc(i) = tSensors.sensorname(k);
                        if contains(tSensors.sensorname(k),"Dimmer")
                            tRules.target(i) = "Dimmer";
                            tRules.location(i) = extractAfter(tSensors.sensorname(k),"Dimmer ");
                        else
                            tRules.target(i) = "Sensor";
                            tRules.location(i) = extractAfter(tSensors.sensorname(k),"Sensor ");
                        end
                        if contains(tRules.location(i), digitsPattern)
                            tRules.location(i) = extractAfter(tRules.RL(i),"Dimmer ");
                        end
                        if ismissing(tRules.location(i))
                            tRules.location(i) = extractAfter(tRules.RL(i),"Dimmer ");
                        end
                        if ismissing(tRules.location(i))
                            tRules.location(i) = extractAfter(tRules.RL(i),"Sensor ");
                        end
                        tRules.id(i) = tSensors.sensorno(k);
                        tRules.description(i) = tSensors.sensorname(k);
                        if ismissing(tRules.location(i))
                            tRules.location(i) = tRules.description(i);
                        end
                        tRules.groupdesc(i) = '*';
                        tRules.scheduledesc(i) = '*';
                        tRules.scenedesc(i) = '*';
                        nxt = true;
                        break;
                    end
                end
                if nxt
                    continue;
                end
                for l = 1:noSchedules(1,2)
                    if tRules.scheduleno(i) == tSchedules.scheduleno(l)
                        tRules.scheduledesc(i) = tSchedules.schedulename(l);
                        tRules.target(i) = "Schedule";
                        tRules.id(i) = tSchedules.scheduleno(l);
                        tRules.description(i) = tSchedules.schedulename(l);
                        tRules.sensordesc(i) = '*';
                        tRules.groupdesc(i) = '*';
                        tRules.scenedesc(i) = '*';
                        nxt = true;
                        break;
                    end
                end
                if nxt
                    continue;
                end
                for m = 1:noScenes(1,2)
                    if extractAfter(tRules.action(i), '/scenes/') == tScenes.sceneID(m)
                        tRules.scenedesc(i) = strcat(tScenes.scenename(m)," / ",tScenes.type(m));
                        tRules.target(i) = "Scene";
                        tRules.id(i) = tScenes.sceneID(m);
                        tRules.description(i) = strcat(tScenes.scenename(m)," / ",tScenes.type(m));
                        tRules.sensordesc(i) = '*';
                        tRules.groupdesc(i) = '*';
                        tRules.scheduledesc(i) = '*';
                        nxt = true;
                        break;
                    end
                end
                if not(nxt) % no match found for any Rules category..
                    tRules.target(i) = "none";
                    tRules.id(i) = 0;
                    tRules.description(i) = '*';
                    tRules.location(i) = '*';
                    tRules.sensordesc(i) = '*';
                    tRules.groupdesc(i) = '*';
                    tRules.scheduledesc(i) = '*';
                    tRules.scenedesc(i) = '*';
                end
            end

            tRsorted = sortrows(tRules,{'timestriggered','lasttriggered','created'},{'descend', 'descend','descend'});
            app.RulesTable.Data = tRsorted(:,1:12);
            app.ONRules.Value = rulesEnabled;
            app.NeverTriggered.Value = rulesNotTrig;

            % Only save extracted Bridge data at startup
            if app.startup
                app.startup = false;
                saveAllData (app,allLights, allRules, allSensors, allGroups, ...
                    allSchedules, allCapabilities, allResourceLinks, allScenes, config)
            else
                dt = datetime('now', 'Format', 'dd-MMM-yyyy HH:mm:ss');
                strDt = string(dt);
                app.LastBridgeDataRefreshTextArea.Value = strDt;
            end

            app.Pulse.setSpeed(10);

            app.Response.Value = toc;

        end

        %%%
        %%% Toggle Lights on/off - if On then switch Off & vice versa
        %%%
        function onOff = toggleLights(app, lightID, index, singleLight)

            if app.vAPI == 1
                onOff = toggleLightsV1(app, lightID, index, singleLight);
            else % API v2
                %% need to set to lightID to tLights.uniqueid(x)
                onOff = toggleLightsV2(app, lightUUID, index, singleLight);
            end

        end

        function onOff = toggleLightsV1(app, numLight, index, singleLight)
            options = weboptions('MediaType', 'application/json', 'RequestMethod', 'put');
            wrURL = sprintf('http://%s/api/%s/lights/%d/state', app.hueIPAddress,app.appID,numLight);
            if app.LightsTable.Data{index,2} == "Room" || singleLight
                if app.LightsTable.Data{index,7} == 1
                    state = struct('on', false);
                    app.lightsOn = app.lightsOn - 1;
                else
                    state = struct('on',true);
                    app.lightsOn = app.lightsOn + 1;
                end
                webwrite(wrURL, state, options);
                app.ONLights.Value = app.lightsOn;
                if app.lightsOn == 1
                    app.LightsOnLabel.Text = 'Light';
                else
                    app.LightsOnLabel.Text = 'Lights';
                end
                blinkLightsOn(app);
            end
            if app.LightsTable.Data{index,7} == 1
                app.LightsTable.Data{index,7} = 0;
                onOff = 0;
            else
                app.LightsTable.Data{index,7} = 1;
                onOff = 1;
            end
        end

        %%% >>> This function cannot be properly tested until a Bridge Pro is installed
        %%% >>> Placeholder only.

        function onOff = toggleLightsV2(app, lightUUID, index, singleLight)
            import matlab.net.http.*
            import matlab.net.http.field.*

            url = "https://" + app.hueIPAddress + "/clip/v2/resource/light/" + lightUUID;
            if app.LightsTable.Data{index,2} == "Room" || singleLight
                isOn = app.LightsTable.Data{index,7} == 1;

                if isOn
                    body = struct('on', struct('on', false));
                    app.lightsOn = app.lightsOn - 1;
                else
                    body = struct('on', struct('on', true));
                    app.lightsOn = app.lightsOn + 1;
                end

                header = HeaderField("hue-application-key", app.appID);
                req = RequestMessage('patch', header, body);
                opts = HTTPOptions('VerifyServerName', false);

                resp = req.send(url, opts);
                app.ONLights.Value = app.lightsOn;
                if app.lightsOn == 1
                    app.LightsOnLabel.Text = 'Light';
                else
                    app.LightsOnLabel.Text = 'Lights';
                end
                blinkLightsOn(app);
            end
            if app.LightsTable.Data{index,7} == 1
                app.LightsTable.Data{index,7} = 0;
                onOff = 0;
            else
                app.LightsTable.Data{index,7} = 1;
                onOff = 1;
            end
        end


        %%%
        %%% Apply change to individual Light Brightness
        %%%

        function updBrightness(app, numLight)
            if app.vAPI == 1
                updBrightnessv1(app, numLight)
            else % API v2
                updBrightnessv2(app, lightUUID)
            end
        end

        function updBrightnessv1(app, numLight)
            options = weboptions('MediaType', 'application/json', 'RequestMethod', 'put');
            wrURL = sprintf('http://%s/api/%s/lights/%d/state', app.hueIPAddress,app.appID,numLight);
            nbri = max(round(app.newBri * 2.54),1);
            state = struct('bri', nbri);
            webwrite(wrURL, state, options);

        end

        %%% >>> This function cannot be properly tested until a Bridge Pro is installed
        %%% >>> Placeholder only.
        function updBrightnessv2(app, lightUUID)

            import matlab.net.http.*
            import matlab.net.http.field.*

            % API v2 endpoint for a single light
            url = "https://" + app.hueIPAddress + "/clip/v2/resource/light/" + lightUUID;

            % Convert v1 bri (0–254) → v2 brightness (0–100)
            briPct = max(round(app.newBri * 2.54), 1);

            % API v2 brightness payload
            body = struct( ...
                'dimming', struct('brightness', briPct) ...
                );

            % Build request
            header = HeaderField("hue-application-key", app.appID);
            req = RequestMessage('patch', header, body);

            % Disable certificate validation (required for Hue Bridge Pro on Windows)
            opts = HTTPOptions('VerifyServerName', false);

            % Send to Bridge Pro
            req.send(url, opts);
        end

        function highlightOnLights(app)
            noLights = size(app.LightsTable.Data);
            for i = 1:noLights(1,1)
                if app.LightsTable.Data{i,7} == 1
                    s1 = uistyle('BackgroundColor','white');
                else
                    s1 = uistyle('BackgroundColor','black');
                end
                addStyle(app.LightsTable,s1,'cell', [i 7]);
            end
        end

        %%%
        %%% Process Brightness change for Groups of Lights
        %%%

        function groupBrightness(app, noLights)
            selGrp = app.LightsTable.Data{app.cellSelIndices(1,1),1};
            for i = 1:noLights(1,1)
                if app.LightsTable.Data{i,1} == selGrp
                    numLight = app.LightsTable.Data{i,3};
                    for j = 1:noLights(1,1)
                        if app.LightsTable.Data{j,3} == numLight ...
                                && app.LightsTable.Data{j,8} ~= app.newBri
                            updBrightness (app, numLight);
                            for k = 1:noLights(1,1) % update all brightness table entries for this light
                                if app.LightsTable.Data{k,3} == numLight
                                    app.LightsTable.Data{k,8} = app.newBri;
                                end
                            end
                            break
                        end
                    end
                end
            end
        end

        %%%
        %%%  Process changes to Light Brightness
        %%%

        function results = newBrightness(app, event)
            indices = event.Indices;
            try
                if event.NewData == event.PreviousData
                    results = "Brightness edited but not changed.";
                    return;
                end
                newData = event.NewData;
                if newData > 100
                    newData = 100;
                elseif newData < 1
                    newData = 1;
                end
            catch EM
                results = "Brightness selected.";
                return;
            end
            app.newBri = newData;
            noLights = size(app.LightsTable.Data);
            if app.cellSelIndices(1,2) == 1 % Group selection previously processed
                groupBrightness (app, noLights);
                results = strcat(app.LightsTable.Data{app.cellSelIndices(1,1),1}, " lights brightness changed.");
            elseif indices(1,2) == 8 % Single Brightness Changed
                numLight = app.LightsTable.Data{indices(1,1),3};
                updBrightness (app, numLight);
                for j = 1:noLights(1,1)
                    if app.LightsTable.Data{j,3} == numLight
                        app.LightsTable.Data{j,8} = newData;
                    end
                end
                results = strcat(app.LightsTable.Data{app.cellSelIndices(1,1),4}, " light brightness changed.");
            end
            app.cellSelIndices = event.Indices;
        end

        %%%
        %%% Update Light Colour
        %%%

        function updColourv(app, numLight)
            if app.vAPI == 1
                updColourv1(app, numLight)
            else % API v2
                updColourv2(app, lightUUID)
            end
        end

        function updColourv1(app, numLight) % API v1
            options = weboptions('MediaType', 'application/json', 'RequestMethod', 'put');
            wrURL = sprintf('http://%s/api/%s/lights/%d/state', app.hueIPAddress,app.appID,numLight);
            state = struct('xy', app.xyColour);
            webwrite(wrURL, state, options);

            lightsTableSize = size(app.LightsTable.Data);
            x = app.xyColour(1,1);
            y = app.xyColour(1,2);
            app.colBri = max(round(app.colBri * 2.54),1);
            rgb = hueXYtoRGB(app,x,y,app.colBri);
            colourName = {nearestCssColourName(app, rgb)};
            s1 = uistyle('BackgroundColor',rgb);
            s2 = uistyle;
            for i=1:lightsTableSize(1,1)
                if app.LightsTable.Data{i,3} == numLight
                    app.LightsTable.Data{i,9} = colourName;
                    addStyle(app.LightsTable,s1,'cell',[i,9]);
                    if sum(rgb) == 0
                        s2.FontColor = 'white';
                    else
                        s2.FontColor = 'black';
                    end
                    addStyle(app.LightsTable,s2,'cell',[i 9]);
                end
            end

        end

        %%% >>> This function cannot be properly tested until a Bridge Pro is installed
        %%% >>> Placeholder only.
        function updColourv2(app, lightUUID) % API v2

            import matlab.net.http.*
            import matlab.net.http.field.*

            % API v2 endpoint for a single light
            url = "https://" + app.hueIPAddress + "/clip/v2/resource/light/" + lightUUID;

            % Convert xy + brightness to API v2 format
            x = app.xyColour(1,1);
            y = app.xyColour(1,2);

            % v1 bri (0–254) → v2 brightness (0–100)
            briPct = max(round(app.colBri * 2.54), 1);

            body = struct( ...
                'color', struct('xy', struct('x', x, 'y', y)), ...
                'dimming', struct('brightness', briPct) ...
                );

            % Build request
            header = HeaderField("hue-application-key", app.appID);
            req = RequestMessage('patch', header, body);
            opts = HTTPOptions('VerifyServerName', false);

            % Send to Bridge Pro
            req.send(url, opts);

            % --- UI UPDATE LOGIC (unchanged) ---
            lightsTableSize = size(app.LightsTable.Data);

            rgb = hueXYtoRGB(app, x, y, briPct);
            colourName = {nearestCssColourName(app, rgb)};
            s1 = uistyle('BackgroundColor', rgb);
            s2 = uistyle;

            for i = 1:lightsTableSize(1)
                if strcmp(app.LightsTable.Data{i,3}, lightUUID) || ...
                        isequal(app.LightsTable.Data{i,3}, lightUUID)
                    app.LightsTable.Data{i,9} = colourName;
                    addStyle(app.LightsTable, s1, 'cell', [i,9]);

                    if sum(rgb) == 0
                        s2.FontColor = 'white';
                    else
                        s2.FontColor = 'black';
                    end

                    addStyle(app.LightsTable, s2, 'cell', [i,9]);
                end
            end
        end


        function coloursChanged = groupColour (app, noLights)
            selGrp = app.LightsTable.Data{app.cellSelIndices(1,1),1};
            coloursChanged = 0;
            for i = 1:noLights(1,1)
                if app.LightsTable.Data{i,1} == selGrp
                    if contains(app.LightsTable.Data{i,6},'c','IgnoreCase',true) || ...
                            contains(app.LightsTable.Data{i,5},'col','IgnoreCase',true)
                        coloursChanged = coloursChanged + 1;
                        numLight = app.LightsTable.Data{i,3};
                        updColourv(app, numLight);
                    end
                end
            end
        end

        function results = newColour(app, event)
            indices = event.Indices;
            noLights = size(app.LightsTable.Data);
            if app.cellSelIndices(1,2) == 1 % Group selection previously processed
                coloursChanged = groupColour(app, noLights);
                if coloursChanged > 0
                    results = strcat(app.LightsTable.Data{app.cellSelIndices(1,1),1}, " lights colour set to ", app.newColr,".");
                else
                    app.LightsTable.Data{event.Indices(1,1),9} = event.PreviousData; % restore previous colour name
                    results = "No colour lights found in selected Group.  Change ignored,";
                end
            elseif indices(1,2) == 9 % Single Colour Changed
                if contains(app.LightsTable.Data{indices(1,1),6},'c','IgnoreCase',true) || ...
                        contains(app.LightsTable.Data{indices(1,1),5},'col','IgnoreCase',true)
                    numLight = app.LightsTable.Data{indices(1,1),3};
                    updColourv(app, numLight);
                    results = strcat(app.LightsTable.Data{app.cellSelIndices(1,1),4}, " light colour set to ", app.newColr, ".");
                else
                    app.LightsTable.Data{event.Indices(1,1),9} = event.PreviousData; % restore previous colour name
                    results = strcat(app.LightsTable.Data{app.cellSelIndices(1,1),4}, " is not a colour light.  Change ignored.");
                end
            end
            app.cellSelIndices = event.Indices;
        end

        %%%
        %%% Connect to Hue Bridge
        %%%

        function [errCode, hueIPAddress, appID, allLights,   ...
                allRules, allSensors, allGroups, allSchedules, allCapabilities, allResourceLinks, allScenes, config] = ...
                connectToBridge(app)

            % Initialise all returned data items

            appID = '';
            allLights = [];
            allRules = [];
            allSensors = [];
            allGroups = [];
            allSchedules = [];
            allCapabilities = [];
            allResourceLinks = [];
            allScenes = [];
            config = [];

            fprintf('Looking up IP address...');
            [errCode, hueIPAddress] = getBridgeIP(app);

            if errCode ~= 0
                return
            end

            % whos hueIPAddress
            fprintf('; Hue IP = %s\n',hueIPAddress);

            if ispc
                app.fName = [app.dataRepository '\userNameHue.mat'];
            else
                app.fName = [app.dataRepository '/userNameHue.mat'];
            end

            if app.vAPI == 1

                fprintf('Checking if already registered to the Hue Bridge...')
                hueUser = [];
                try
                    load(app.fName);  % userNameHue
                    appID     = hueUser;
                    url        = sprintf('http://%s/api/%s/', hueIPAddress,appID);
                    deviceInfo = webread(url); % check if authorised
                    notInBridgeWhitelist = isfield(deviceInfo,'error');
                catch MSG
                    % Cater for lack of internet..
                    if contains(MSG.message,'not connect to server')
                        errCode = 999;
                        warning('No internet connection.');
                        return;
                    end
                    notInBridgeWhitelist = true;
                end

                if notInBridgeWhitelist
                    fprintf('Not registered with Bridge. Attempting connection.\n')
                    appID = registerWithBridge(app, hueIPAddress, app.fName);
                    if ~isempty(appID)
                        fprintf('Connection with Hue Bridge completed. Added to Whitelist. \n')
                    else
                        fprintf('Connection process terminated. \n')
                        errCode = 888;
                        return;
                    end
                else
                    fprintf('Connection with Hue Bridge established; using registered user-name (appID)\n')
                end


                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %%% Gather Bridge data to return for further processing..
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                url        = sprintf('http://%s/api/%s/lights', hueIPAddress,appID);
                response = webread(url);
                if ~isfield(response,'error')
                    allLights = response;
                else % warning for first read failure to aid diagnosis - error for any subsequent failures
                    warning ('** Unable to access Lights details **');
                    save([app.dataRepository 'diagnostics.mat'],"response");
                    disp(url);
                end

                url = sprintf('http://%s/api/%s/rules', hueIPAddress,appID);
                response = webread(url);
                if ~isfield(response,'error')
                    allRules = response;
                else
                    error ('** Unable to access Rules details **');
                end

                url = sprintf('http://%s/api/%s/sensors', hueIPAddress,appID);
                response = webread(url);
                if ~isfield(response,'error')
                    allSensors = response;
                else
                    error ('** Unable to access Sensors details **');
                end

                url = sprintf('http://%s/api/%s/groups', hueIPAddress,appID);
                response = webread(url);
                if ~isfield(response,'error')
                    allGroups = response;
                else
                    error ('** Unable to access Groups details **');
                end

                url = sprintf('http://%s/api/%s/schedules', hueIPAddress,appID);
                response = webread(url);
                if ~isfield(response,'error')
                    allSchedules = response;
                else
                    error ('** Unable to access Schedules details **');
                end

                url = sprintf('http://%s/api/%s/capabilities', hueIPAddress,appID);
                response = webread(url);
                if ~isfield(response,'error')
                    allCapabilities = response;
                else
                    error ('** Unable to access Capabilities details **');
                end


                url = sprintf('http://%s/api/%s/config', hueIPAddress,appID);
                response = webread(url);
                if ~isfield(response,'error')
                    config = response;
                else
                    error ('** Unable to access Config details **');
                end

                url = sprintf('http://%s/api/%s/resourcelinks', hueIPAddress,appID);
                response = webread(url);
                if ~isfield(response,'error')
                    allResourceLinks = response;
                else
                    error ('** Unable to access Resourcelinks details **');
                end

                url = sprintf('http://%s/api/%s/scenes', hueIPAddress,appID);
                response = webread(url);
                if ~isfield(response,'error')
                    allScenes = response;
                else
                    error ('** Unable to access Scenes details **');
                end

            else % API v2

                fprintf('Checking if already registered to the Hue Bridge (API v2)...\n');
                hueUser = [];  % hueAppkey for v2
                try
                    % Load stored application key
                    load(app.fName);   % loads appID
                    appID = hueUser;

                    % Validate key by calling a harmless v2 endpoint
                    import matlab.net.http.*
                    import matlab.net.http.field.*

                    url = "https://" + hueIPAddress + "/clip/v2/resource/bridge";

                    header = HeaderField("hue-application-key", appID);
                    req = RequestMessage('get', header);

                    opts = HTTPOptions( ...
                        'VerifyServerName', false, ...
                        'CertificateFilename', '' ...   % <‑‑ THIS disables CA validation
                        );

                    resp = req.send(url, opts);

                    % If unauthorized, Bridge returns 403
                    notRegistered = resp.StatusCode ~= matlab.net.http.StatusCode.OK;

                catch MSG
                    disp(MSG);
                    if contains(MSG.message,'not connect to server')
                        errCode = 999;
                        warning('No internet connection.');
                        return;
                    end
                    notRegistered = true;
                end

                if notRegistered
                    fprintf('Not registered with Bridge Pro. Attempting pairing...\n');

                    appID = registerWithBridgePro(app, hueIPAddress, [app.fName 'Pro']);

                    if ~isempty(appID)
                        fprintf('Pairing completed. Application key stored.\n');
                    else
                        fprintf('Pairing process terminated.\n');
                        errCode = 888;
                        return;
                    end
                else
                    fprintf('Connection established; using stored application key.\n');
                end


                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %%% Gather Bridge data (API v2 drop‑in replacement)
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                loadAllResources(app);

                % >>>


                % save("allAPIv2Data.mat","data"); % debug

                v1.lights = convertV2LightsToV1(app, allDevices, resourceMap);
                v1.groups        = convertV2GroupsToV1(app, app.groups,        app.resourceMap);
                v1.scenes        = convertV2ScenesToV1(app, app.scenes,        app.resourceMap);
                v1.rules         = convertV2RulesToV1(app, app.rules,          app.resourceMap);
                v1.schedules     = convertV2SchedulesToV1(app, app.schedules,  app.resourceMap);
                v1.capabilities  = convertV2CapabilitiesToV1(app, app.capabilities);
                v1.resourcelinks = convertV2ResourceLinksToV1(app, app.resourcelinks);
                v1.sensors       = convertV2SensorsToV1(app, app.devices,      app.resourceMap);


                saveAllData (app,allLights, allRules, allSensors, allGroups, ...
                    allSchedules, allCapabilities, allResourceLinks, allScenes, config)

                fprintf('\n\n');
            end
        end

        %%%
        %%% New Bridge registration - process for adding new user to WhiteList
        %%%

        function appID = registerWithBridge(app, hueIPAddress, fNameSAVE)
            appID = [];
            app.DiagnosticsMsgTextArea.Value = 'Attempting to pair with the Bridge..';
            lastDiagMsg = app.DiagnosticsMsgTextArea.Value;
            save (app.diagFileName,"lastDiagMsg");
            fprintf('\nAttempting to pair with the Bridge..');
            url  = sprintf('http://%s/api', hueIPAddress);
            options = weboptions('MediaType', 'application/json');
            deviceType = struct('devicetype', 'SPF_Matlab_API');
            response = webwrite (url, deviceType, options);

            if isfield(response,'success')
                finished = true;
            else
                finished = false;
            end

            if ~finished

                Msgbox = uifigure;
                message = "Need to obtain a valid Username to connect to Bridge v2; Proceed with Pairing? (Will need to press the link button on the bridge.)";
                selection1 = uiconfirm(Msgbox,message,"Bridge Registration Process", ...
                    "Options",["Yes","No (Cancel)"],...
                    "DefaultOption",1,"CancelOption",2,....
                    "Icon","question");
                switch selection1
                    case 'Yes'
                        message = "Press the Link Button on the Hue Bridge v2 within 60 seconds.";
                        selection2 = uiconfirm(Msgbox,message,"Bridge v2 Registration Process", ...
                            "Options",["Continue","Cancel"],...
                            "DefaultOption",1,"CancelOption",2,....
                            "Icon","info");
                        switch selection2
                            case 'Continue'
                                uialert(Msgbox,"Press the Link button on the Bridge v2","OK");
                                app.DiagnosticsMsgTextArea.Value = 'Waiting for Link Button press..';
                                lastDiagMsg = app.DiagnosticsMsgTextArea.Value;
                                save (app.diagFileName,"lastDiagMsg");
                                drawnow
                            case 'Cancel'
                                app.DiagnosticsMsgTextArea.Value = 'Cancelled.  Not paired.';
                                lastDiagMsg = app.DiagnosticsMsgTextArea.Value;
                                save (app.diagFileName,"lastDiagMsg");
                                drawnow
                                pause(3)
                                delete(Msgbox);
                                return
                        end
                    case 'No (Cancel)'
                        app.DiagnosticsMsgTextArea.Value = 'Cancelled.  Not paired.';
                        lastDiagMsg = app.DiagnosticsMsgTextArea.Value;
                        save (app.diagFileName,"lastDiagMsg");
                        drawnow
                        pause(3)
                        delete(Msgbox);
                        return
                end
            end

            tic
            while ~finished && toc < 60
                try
                    response = webwrite (url, deviceType, options);
                    if isfield(response,'success')
                        finished = true;
                    end
                catch % wait for link button being pressed, unless it's a real error
                    if contains(response.error.description, 'link button not pressed')
                        pause(5)
                    else
                        fprintf('\n* Unable to communicate with the Bridge v2 *\n');
                        app.DiagnosticsMsgTextArea.Value = 'Unable to communicate with the Bridge v2.  Not paired.';
                        lastDiagMsg = app.DiagnosticsMsgTextArea.Value;
                        save (app.diagFileName,"lastDiagMsg","response");
                        pause(3)
                        delete(Msgbox);
                        return
                    end
                end
            end

            if toc >= 60
                fprintf('\n* Link button on Bridge v2 not pressed within 60 seconds.  Timed out. *\n');
                app.DiagnosticsMsgTextArea.Value = 'Link button on Bridge v2 not pressed within 60 seconds.  Timed out.';
                lastDiagMsg = app.DiagnosticsMsgTextArea.Value;
                save (app.diagFileName,"lastDiagMsg");
                drawnow
                pause(3)
                delete(Msgbox);
                return
            end

            appID = response.success.username;
            hueUser = appID;
            save(fNameSAVE,'hueUser');
            app.DiagnosticsMsgTextArea.Value = 'Bridge v2 registration successful (added to Whitelist).  Restarting the App in 5 seconds...';
            lastDiagMsg = app.DiagnosticsMsgTextArea.Value;
            save (app.diagFileName,"lastDiagMsg");
            pause(5)
            delete(Msgbox);
            restart(app);
        end

        function appKey = registerWithBridgePro(app, hueIPAddress, fName)

            import matlab.net.http.*
            import matlab.net.http.field.*

            url = "https://" + hueIPAddress + "/clip/v2/resource/device";

            body = struct( ...
                'create', struct( ...
                'device', struct( ...
                'device_type', 'MATLAB_App' ...
                ) ...
                ) ...
                );

            header = HeaderField("hue-application-key", ""); % empty for pairing
            req = RequestMessage('post', header, body);
            opts = HTTPOptions( ...
                'VerifyServerName', false, ...
                'CertificateFilename', '' ...   % <‑‑ THIS disables CA validation
                );


            fprintf('Press the link button on the Bridge Pro...\n');
            app.DiagnosticsMsgTextArea.Value = 'Press the link button on the Bridge Pro within 30 seconds..';
            drawnow
            pause(30);

            try
                resp = req.send(url, opts);
                if resp.StatusCode == matlab.net.http.StatusCode.OK
                    appKey = resp.Body.Data.data(1).hue_application_key;
                    hueAppKey = appKey;
                    save(fName, "hueAppKey");
                    app.DiagnosticsMsgTextArea.Value = 'Bridge Pro registration successful (added to Whitelist).  Restarting the App in 5 seconds...';
                    drawnow
                    pause(5);
                    restart(app);
                end
            catch
                appKey = [];
                try
                    if contains(resp.error.description, 'link button not pressed') % error tbc after Bridge Pro installed
                        fprintf('\n* Link button on Bridge Pro not pressed within 30 seconds.  Timed out. *\n');
                        app.DiagnosticsMsgTextArea.Value = 'Link button on Bridge Pro not pressed within 30 seconds.  Timed out.';
                    else % to be determined after Bridge Pro installed
                        fprintf('\n* Undetermined error during Bridge Pro registration. *\n');
                        app.DiagnosticsMsgTextArea.Value = 'Undetermined error during Bridge Pro registration.';
                    end
                    drawnow;
                    pause(10)
                catch
                    fprintf('\n* Unable to communicate with the Bridge Pro *\n');
                    app.DiagnosticsMsgTextArea.Value = 'Unable to communicate with the Bridge Pro.  Not paired.';
                    drawnow;
                    pause(10);
                end
                lastDiagMsg = app.DiagnosticsMsgTextArea.Value;
                save (app.diagFileName,"lastDiagMsg");
            end
        end


        %%%
        %%% Get the Bridge IP address - if not available or no longer valid then obtain a new one..
        %%%

        function [errCode, hueIPAddress] = getBridgeIP(app)

            % First try to use previously stored IP address to save unnecessary external webreads
            errCode = 0;

            if ispc
                IPFile = [app.dataRepository '\hueIPAddress.mat'];
                userFile = [app.dataRepository '\userNameHue.mat'];
            else
                IPFile = [app.dataRepository '/hueIPAddress.mat'];
                userFile = [app.dataRepository '/userNameHue.mat'];
            end
            app.fName = IPFile;
            try
                load(IPFile,"v2");
                hueIPAddress = v2(1).internalipaddress;
                load(userFile,"hueUser");
                app.appID = hueUser;
                fprintf('Using previously stored IP address from %s.',IPFile);
                if app.vAPI == 1
                    try
                        url = sprintf('http://%s/api/%s/config', hueIPAddress,app.appID);
                        % check that the details still work..
                        webread(url);
                        return; % still valid
                    catch MSG
                        disp(MSG.message)
                    end
                else % API v2
                    if isValidHueV2IP(app, hueIPAddress)
                        return; % still valid
                    end
                end
            catch MSG
                disp(MSG)
                hueIPAddress = 'IP file not found';
            end

            % Previous IP no longer valid
            % Try to get fresh details from the web
            fprintf('\nIP address changed.  Obtaining new IP address..');
            [v2, errCode] = discoverV2Bridges(app);
            if errCode ~= 0
                return;
            end

            if isempty(v2)
                % error('No Hue Bridge v2 found on the network.');
                errCode = 777;
                return;
            end
            hueIPAddress = v2(1).internalipaddress;
            save(IPFile,"v2");

        end

        %%%
        %%% Saves all Bridge data for external analysis (not obligatory)
        %%%

        function saveAllData(app,allLights, allRules, allSensors, allGroups, ...
                allSchedules, allCapabilities, allResourceLinks, allScenes, config)
            if ispc
                % Save all data for subsequent analysis
                save([app.dataRepository '\allLightsData.mat'],"allLights");
                save([app.dataRepository '\allRulesData.mat'],"allRules");
                save([app.dataRepository '\allSensorsData.mat'],"allSensors");
                save([app.dataRepository '\allGroupsData.mat'],"allGroups");
                save([app.dataRepository '\allSchedulesData.mat'],"allSchedules");
                save([app.dataRepository '\allCapabilitiesData.mat'],"allCapabilities");
                save([app.dataRepository '\allScenesData.mat'],"allScenes");
                save([app.dataRepository '\allResourceLinksData.mat'],"allResourceLinks");
                save([app.dataRepository '\config.mat'],"config");
            else
                save([app.dataRepository '/allLightsData.mat'],"allLights");
                save([app.dataRepository '/allRulesData.mat'],"allRules");
                save([app.dataRepository '/allSensorsData.mat'],"allSensors");
                save([app.dataRepository '/allGroupsData.mat'],"allGroups");
                save([app.dataRepository '/allSchedulesData.mat'],"allSchedules");
                save([app.dataRepository '/allCapabilitiesData.mat'],"allCapabilities");
                save([app.dataRepository '/allScenesData.mat'],"allScenes");
                save([app.dataRepository '/allResourceLinksData.mat'],"allResourceLinks");
                save([app.dataRepository '/config.mat'],"config");
            end

            listing = dir("**");
            tbl = struct2table(listing);
            tbl.date = datetime(tbl.datenum,ConvertFrom="datenum");
            tbl = removevars(tbl,"datenum");
            dets = tbl(tbl.name == "allCapabilitiesData.mat",3);
            app.LastBridgeDataRefreshTextArea.Value = string(dets.date(1,1));
        end

        %%%
        %%% Saves all Bridge data for external analysis (not obligatory)
        %%%

        function [allLights, allRules, allSensors, allGroups, ...
                allSchedules, allCapabilities, allResourceLinks, allScenes, config] ...
                = loadAllData(app);
            if ispc
                % Save all data for subsequent analysis
                load([app.dataRepository '\allLightsData.mat'],"allLights");
                load([app.dataRepository '\allRulesData.mat'],"allRules");
                load([app.dataRepository '\allSensorsData.mat'],"allSensors");
                load([app.dataRepository '\allGroupsData.mat'],"allGroups");
                load([app.dataRepository '\allSchedulesData.mat'],"allSchedules");
                load([app.dataRepository '\allCapabilitiesData.mat'],"allCapabilities");
                load([app.dataRepository '\allScenesData.mat'],"allScenes");
                load([app.dataRepository '\allResourceLinksData.mat'],"allResourceLinks");
                load([app.dataRepository '\config.mat'],"config");
            else
                load([app.dataRepository '/allLightsData.mat'],"allLights");
                load([app.dataRepository '/allRulesData.mat'],"allRules");
                load([app.dataRepository '/allSensorsData.mat'],"allSensors");
                load([app.dataRepository '/allGroupsData.mat'],"allGroups");
                load([app.dataRepository '/allSchedulesData.mat'],"allSchedules");
                load([app.dataRepository '/allCapabilitiesData.mat'],"allCapabilities");
                load([app.dataRepository '/allScenesData.mat'],"allScenes");
                load([app.dataRepository '/allResourceLinksData.mat'],"allResourceLinks");
                load([app.dataRepository '/config.mat'],"config");
            end

            listing = dir("**");
            tbl = struct2table(listing);
            tbl.date = datetime(tbl.datenum,ConvertFrom="datenum");
            tbl = removevars(tbl,"datenum");
            dets = tbl(tbl.name == "allCapabilitiesData.mat",3);
            app.LastBridgeDataRefreshTextArea.Value = string(dets.date(1,1));
        end

        %%%
        %%% Restart the app after whitelist addition or IP address refresh
        %%%

        function restart(app)
            try
                stop(app.UpdateTimer);
                delete(app.UpdateTimer);
            catch
            end
            app.restoreWindowPos = false;
            startupFcn(app);              % Launch a new instance of the app
        end

        %%%
        %%% Blink the number of lights on value
        %%%

        function blinkLightsOn(app)

            % Fast-ish blink (3 seconds per cycle)
            app.HTMLLightsOn.Data = struct( ...
                'message', num2str(app.lightsOn), ...
                'color', 'white', ...
                'fontsize', '36px', ...
                'fontweight', 'bold', ...
                'lineheight', '0', ...
                'width', '55px', ...
                'height', '45px', ...
                'speed', '3s');
        end

        % Capture new sequence of Lights details when sorted via UI
        function LightsTableDisplayDataChanged(app, event)
            % Get the displayed (sorted/filtered) data
            newData = event.DisplayData;
            % Update the underlying Data property
            app.LightsTable.Data = newData;
        end

        %%%
        %%% Determine how many Groups and Rooms have at least one Light lit after Toggling
        %%%

        function updateONGroupsRooms(app)
            app.OnGroups.Value = 0;
            app.roomLightsOn = 0;
            app.tGroups.anyon(1:end) = false;
            noLights = size(app.LightsTable.Data);
            for i = 1:noLights(1,1)
                if app.LightsTable.Data{i,7} == 1
                    for j = 1:app.AVGroups.Value
                        if app.LightsTable.Data{i,1} == app.tGroups.groupname(j)
                            app.tGroups.anyon(j) = true;
                            break;
                        end
                    end
                end
            end
            for i = 1:app.AVGroups.Value
                if app.tGroups.anyon(i) == true
                    app.OnGroups.Value = app.OnGroups.Value + 1;
                    if app.tGroups.type(i) == "Room"
                        app.roomLightsOn = app.roomLightsOn + 1;
                    end
                end
            end
            app.noRoomsLightsOn.Text = num2str(app.roomLightsOn);
            if app.roomLightsOn == 1
                app.RoomsLabel.Text = 'Room';
            else
                app.RoomsLabel.Text = 'Rooms';
            end
        end

        %%%
        %%% Full close-down and restart
        %%%

        function restartApp(app)
            disp('restartApp')
            if app.offlineMode
                return
            end

            cd (app.appFolder)
            warning('on', 'all');
            saveWindowState(app);
            appName = mfilename;

            try
                stop(app.UpdateTimer);
                delete(app.UpdateTimer);
            catch
            end

            delete(app);
            feval(appName);

        end

        %%%
        %%% Convert Philips Hue xy + brightness to MATLAB RGB (0–1)
        %%%

        function rgb = hueXYtoRGB(~,x, y, bri)
            % Convert Philips Hue xy + brightness to App-style RGB (0–255)

            if y == 0
                rgb = [0 0 0];
                return;
            end

            % Step 1: xy + brightness → XYZ
            Y = bri / 254;          % Normalised luminance
            X = (Y / y) * x;
            Z = (Y / y) * (1 - x - y);
            XYZ = [X; Y; Z];

            % Step 2: XYZ → linear RGB (sRGB matrix)
            M = [ 3.2406 -1.5372 -0.4986;
                -0.9689  1.8758  0.0415;
                0.0557 -0.2040  1.0570 ];

            rgb_lin = M * XYZ;

            % Step 3: Clip negative values
            rgb_lin = max(rgb_lin, 0);

            % Step 4: sRGB gamma correction
            gammaCorrect = @(c) (c <= 0.0031308) .* (12.92 * c) + ...
                (c > 0.0031308)  .* (1.055 * c .^ (1/2.4) - 0.055);

            rgb = gammaCorrect(rgb_lin);

            % Step 5: Scale to 0–255
            rgb = uint8(round(255 * rgb / max(rgb)));
        end


        %%%
        %%% Save and restore UIFigure placement
        %%%

        function saveWindowState(app)
            appName = class(app);
            fig = app.getAppFigure;
            setpref(appName, "WindowPosition", fig.Position);
            setpref(appName, "WindowState", fig.WindowState);
        end

        function restoreWindowState(app)
            appName = class(app);
            fig = app.getAppFigure;

            % Restore window state
            if ispref(appName, "WindowState")
                fig.WindowState = getpref(appName, "WindowState");
            end

            % Restore position only if not maximized
            if ispref(appName, "WindowPosition") && fig.WindowState == "normal"
                pos = getpref(appName, "WindowPosition");

                % Get true minimum size
                [minW, minH] = app.getMinimumAppSize;

                % Enforce minimum size
                pos(3) = max(pos(3), minW);
                pos(4) = max(pos(4), minH);

                % Avoid off-screen placement
                screens = get(0, "MonitorPositions");
                if any(pos(1) >= screens(:,1) & pos(1) <= screens(:,1)+screens(:,3))
                    fig.Position = pos;
                end
            end
        end

        function fig = getAppFigure(app)
            % Find the UIFigure property dynamically
            props = properties(app);

            for i = 1:numel(props)
                val = app.(props{i});
                if isa(val, 'matlab.ui.Figure')
                    fig = val;
                    return
                end
            end
            error('No UIFigure found in app.');
        end

        function [minW, minH] = getMinimumAppSize(app)
            fig = app.getAppFigure;

            % Temporarily shrink the window to its smallest allowed size
            originalPos = fig.Position;

            % Try shrinking width
            fig.Position(3) = 1;
            drawnow limitrate
            minW = fig.Position(3);

            % Try shrinking height
            fig.Position(4) = 1;
            drawnow limitrate
            minH = fig.Position(4);

            % Restore original size
            fig.Position = originalPos;
        end

        %%%
        %%% Convert RGB to nearest matching colour (basic)
        %%%

        function name = nearestColourName(~, rgb)

            % Ensure double
            rgb = double(rgb);

            % rgb can be 0–1 or 0–255
            if max(rgb) > 1
                rgb = rgb / 255;
            end

            % Basic CSS-style colour list
            names = { ...
                'black','white','red','green','blue','yellow','cyan','magenta', ...
                'gray','orange','pink','purple','brown','lime','navy','teal'};

            values = [ ...
                0   0   0;      % black
                1   1   1;      % white
                1   0   0;      % red
                0   1   0;      % green
                0   0   1;      % blue
                1   1   0;      % yellow
                0   1   1;      % cyan
                1   0   1;      % magenta
                0.5 0.5 0.5;    % gray
                1   0.5 0;      % orange
                1   0.75 0.8;   % pink
                0.5 0   0.5;    % purple
                0.6 0.3 0;      % brown
                0.75 1 0;       % lime
                0   0   0.5;    % navy
                0   0.5 0.5];   % teal

            % Compute Euclidean distance
            rgb = reshape(rgb, 1, 3);   % ensure 1×3
            diffs = values - rgb(ones(size(values,1),1), :);
            dist = sqrt(sum(diffs.^2,2));

            [~, idx] = min(dist);
            name = names{idx};
        end

        %%%
        %%% Convert RGB to nearest matching CSS colour (detailed)
        %%%

        function name = nearestCssColourName(~, rgb)
            % Return nearest CSS colour name to an RGB triplet.
            % name = nearestCssColorName([R G B])
            % Accepts 0–255 or 0–1 RGB input.

            % Ensure double
            rgb = double(rgb);

            % Normalise if needed
            if max(rgb) > 1
                rgb = rgb / 255;
            end

            % CSS Level 4 colour names and RGB values
            data = {
                'aliceblue',            [240 248 255]
                'antiquewhite',         [250 235 215]
                'aqua',                 [  0 255 255]
                'aquamarine',           [127 255 212]
                'azure',                [240 255 255]
                'beige',                [245 245 220]
                'bisque',               [255 228 196]
                'black',                [  0   0   0]
                'blanchedalmond',       [255 235 205]
                'blue',                 [  0   0 255]
                'blueviolet',           [138  43 226]
                'brown',                [165  42  42]
                'burlywood',            [222 184 135]
                'cadetblue',            [ 95 158 160]
                'chartreuse',           [127 255   0]
                'chocolate',            [210 105  30]
                'coral',                [255 127  80]
                'cornflowerblue',       [100 149 237]
                'cornsilk',             [255 248 220]
                'crimson',              [220  20  60]
                'cyan',                 [  0 255 255]
                'darkblue',             [  0   0 139]
                'darkcyan',             [  0 139 139]
                'darkgoldenrod',        [184 134  11]
                'darkgray',             [169 169 169]
                'darkgreen',            [  0 100   0]
                'darkgrey',             [169 169 169]
                'darkkhaki',            [189 183 107]
                'darkmagenta',          [139   0 139]
                'darkolivegreen',       [ 85 107  47]
                'darkorange',           [255 140   0]
                'darkorchid',           [153  50 204]
                'darkred',              [139   0   0]
                'darksalmon',           [233 150 122]
                'darkseagreen',         [143 188 143]
                'darkslateblue',        [ 72  61 139]
                'darkslategray',        [ 47  79  79]
                'darkslategrey',        [ 47  79  79]
                'darkturquoise',        [  0 206 209]
                'darkviolet',           [148   0 211]
                'deeppink',             [255  20 147]
                'deepskyblue',          [  0 191 255]
                'dimgray',              [105 105 105]
                'dimgrey',              [105 105 105]
                'dodgerblue',           [ 30 144 255]
                'firebrick',            [178  34  34]
                'floralwhite',          [255 250 240]
                'forestgreen',          [ 34 139  34]
                'fuchsia',              [255   0 255]
                'gainsboro',            [220 220 220]
                'ghostwhite',           [248 248 255]
                'gold',                 [255 215   0]
                'goldenrod',            [218 165  32]
                'gray',                 [128 128 128]
                'green',                [  0 128   0]
                'greenyellow',          [173 255  47]
                'grey',                 [128 128 128]
                'honeydew',             [240 255 240]
                'hotpink',              [255 105 180]
                'indianred',            [205  92  92]
                'indigo',               [ 75   0 130]
                'ivory',                [255 255 240]
                'khaki',                [240 230 140]
                'lavender',             [230 230 250]
                'lavenderblush',        [255 240 245]
                'lawngreen',            [124 252   0]
                'lemonchiffon',         [255 250 205]
                'lightblue',            [173 216 230]
                'lightcoral',           [240 128 128]
                'lightcyan',            [224 255 255]
                'lightgoldenrodyellow', [250 250 210]
                'lightgray',            [211 211 211]
                'lightgreen',           [144 238 144]
                'lightgrey',            [211 211 211]
                'lightpink',            [255 182 193]
                'lightsalmon',          [255 160 122]
                'lightseagreen',        [ 32 178 170]
                'lightskyblue',         [135 206 250]
                'lightslategray',       [119 136 153]
                'lightslategrey',       [119 136 153]
                'lightsteelblue',       [176 196 222]
                'lightyellow',          [255 255 224]
                'lime',                 [  0 255   0]
                'limegreen',            [ 50 205  50]
                'linen',                [250 240 230]
                'magenta',              [255   0 255]
                'maroon',               [128   0   0]
                'mediumaquamarine',     [102 205 170]
                'mediumblue',           [  0   0 205]
                'mediumorchid',         [186  85 211]
                'mediumpurple',         [147 112 219]
                'mediumseagreen',       [ 60 179 113]
                'mediumslateblue',      [123 104 238]
                'mediumspringgreen',    [  0 250 154]
                'mediumturquoise',      [ 72 209 204]
                'mediumvioletred',      [199  21 133]
                'midnightblue',         [ 25  25 112]
                'mintcream',            [245 255 250]
                'mistyrose',            [255 228 225]
                'moccasin',             [255 228 181]
                'navajowhite',          [255 222 173]
                'navy',                 [  0   0 128]
                'oldlace',              [253 245 230]
                'olive',                [128 128   0]
                'olivedrab',            [107 142  35]
                'orange',               [255 165   0]
                'orangered',            [255  69   0]
                'orchid',               [218 112 214]
                'palegoldenrod',        [238 232 170]
                'palegreen',            [152 251 152]
                'paleturquoise',        [175 238 238]
                'palevioletred',        [219 112 147]
                'papayawhip',           [255 239 213]
                'peachpuff',            [255 218 185]
                'peru',                 [205 133  63]
                'pink',                 [255 192 203]
                'plum',                 [221 160 221]
                'powderblue',           [176 224 230]
                'purple',               [128   0 128]
                'rebeccapurple',        [102  51 153]
                'red',                  [255   0   0]
                'rosybrown',            [188 143 143]
                'royalblue',            [ 65 105 225]
                'saddlebrown',          [139  69  19]
                'salmon',               [250 128 114]
                'sandybrown',           [244 164  96]
                'seagreen',             [ 46 139  87]
                'seashell',             [255 245 238]
                'sienna',               [160  82  45]
                'silver',               [192 192 192]
                'skyblue',              [135 206 235]
                'slateblue',            [106  90 205]
                'slategray',            [112 128 144]
                'slategrey',            [112 128 144]
                'snow',                 [255 250 250]
                'springgreen',          [  0 255 127]
                'steelblue',            [ 70 130 180]
                'tan',                  [210 180 140]
                'teal',                 [  0 128 128]
                'thistle',              [216 191 216]
                'tomato',               [255  99  71]
                'turquoise',            [ 64 224 208]
                'violet',               [238 130 238]
                'wheat',                [245 222 179]
                'white',                [255 255 255]
                'whitesmoke',           [245 245 245]
                'yellow',               [255 255   0]
                'yellowgreen',          [154 205  50]
                };

            names  = data(:,1);
            values = double(cell2mat(data(:,2))) / 255;

            % Compute Euclidean distance
            rgb = reshape(rgb, 1, 3);   % ensure 1×3

            diffs = values - rgb(ones(size(values,1),1), :);

            dist  = sqrt(sum(diffs.^2,2));

            [~, idx] = min(dist);
            name = names{idx};
        end

        %%%
        %%% Check if any motions detected since last Refresh.  If so, get fresh Bridge data.
        %%%

        function onTimerTick(app)
            if ~ app.autoRefresh
                return
            end
            % disp('Timer')
            url = sprintf('http://%s/api/%s/sensors', app.hueIPAddress,app.appID);
            response = webread(url);
            if ~isfield(response,'error')
                allSensors = response;
            else
                error ('** Unable to access Sensors details **');
            end
            tableSensors = struct2table(allSensors);
            noSensors = size(tableSensors);
            fieldX = fieldnames(allSensors(1,1));
            newEvent = false;
            for i = 1:noSensors(1,2)
                try
                    lastEvent = tableSensors.(fieldX{i,1}).state.lastupdated;
                    productName = tableSensors.(fieldX{i,1}).productname;
                    sensorType = tableSensors.(fieldX{i,1}).type;
                    sensorName = tableSensors.(fieldX{i,1}).name;
                    if contains(productName,'motion') && contains(sensorType,"Presence")
                        % fprintf('sensorName: %s - productName: %s - lastEvent: %s\n',sensorName, productName, lastEvent);
                        %  fprintf('lastRefresh: %s - sensorNameLastMovement: %s\n',app.lastRefresh, app.sensorNameLastMovement);
                        if lastEvent > app.lastRefresh && app.sensorNameLastMovement ~= sensorName
                            % fprintf('**** lastRefresh: %s - lastEvent: %s\n', app.lastRefresh, lastEvent);
                            app.sensorNameLastMovement = sensorName;
                            newEvent = true;
                            break;
                        end
                    end
                catch
                end
            end
            if newEvent
                refreshDetails(app);
                app.autoRefreshCount = app.autoRefreshCount + 1;
                app.lastRefresh = datetime("now");
                app.DiagnosticsMsgTextArea.Value = append('Auto-refresh ',num2str(app.autoRefreshCount),' triggered by ',sensorName, ' movement detection.');
            end
        end

        %%%
        %%% Refresh Bridge Details
        %%%

        function refreshDetails(app)
            % clc

            stopTimer(app);
            delete(app.UpdateTimer);
            startTimer(app)
            % Change speed (faster pulse)
            app.Pulse.setSpeed(0.5);

            if app.responseLimit == 0
                app.responseLimit = round(app.Response.Value * 1.25);
            end
            app.RefreshButton.Visible ="off";
            app.DiagnosticsMsgTextArea.Value = '';
            app.LastBridgeDataRefreshTextArea.Value = 'Reading from Bridge..';
            prevColour = app.LastBridgeDataRefreshTextArea.FontColor;
            app.LastBridgeDataRefreshTextArea.FontColor = "white";
            if app.ResponseLimitSwitch.Value == "On" && ~ app.offlineMode
                app.DiagnosticsMsgTextArea.Value = append('Extracting details from the Bridge..(Response Limit set to ',num2str(app.responseLimit), ' seconds.)');
            else
                if app.offlineMode
                    app.DiagnosticsMsgTextArea.Value = 'Loading previously saved details..';
                else
                    app.DiagnosticsMsgTextArea.Value = 'Extracting details from the Bridge..';
                end
            end
            drawnow;
            errCode = getStats (app, 'refresh');
            if app.ResponseLimitSwitch.Value == "On"
                if toc > app.responseLimit
                    fprintf('** Restarting to renew resources after exceeding %d seconds response limit by %d second(s). **\n', app.responseLimit,round(toc-app.responseLimit));
                    restartDetails= append('Restarted to renew resources after exceeding previous ',num2str(app.responseLimit), 's response limit.');
                    if ispc
                        save([app.dataRepository '\hueAppDiagnostics'],"restartDetails");
                    else
                        save([app.dataRepository '/hueAppDiagnostics'],"restartDetails");
                    end
                    restartApp(app);
                    return
                end
            end
            % clc
            app.LastBridgeDataRefreshTextArea.FontColor = prevColour;
            dt = datetime('now', 'Format', 'dd/MM/yy HH:mm:ss');
            strDt = string(dt);
            app.DiagnosticsTimeTextArea.Value = strDt;
            if errCode == 0
                if app.offlineMode
                    app.DiagnosticsMsgTextArea.Value = 'Retrieved previously saved Bridge data.';
                else
                    app.DiagnosticsMsgTextArea.Value = 'Fresh data retrieved from the Bridge.';
                end
            elseif ~ app.offlineMode
                app.DiagnosticsMsgTextArea.Value = [app.DiagnosticsMsgTextArea.Value '  Refresh failed.'];
                lastDiagMsg = app.DiagnosticsMsgTextArea.Value;
                save (app.diagFileName,"lastDiagMsg");
                app.successfulStart = false;
            end
            app.RefreshButton.Visible = "on";
            app.RefreshButton.Tooltip = '';
            app.lastRefresh = datetime("now");
        end

        %%%
        %%% Timer functions
        %%%

        function startTimer(app)
            app.UpdateTimer = timer( ...
                'ExecutionMode', 'fixedRate', ...
                'Period', 15.0, ...
                'TimerFcn', @(~,~) app.onTimerTick() );
            start(app.UpdateTimer)
        end
        function stopTimer(app)
            try
                stop(app.UpdateTimer)
                delete(app.UpdateTimer)
            catch
            end
        end

        %%%
        %%% Determine which API version to use depending on Bridge
        %%%

        function apiVersion = detectHueApiVersion(app)

            import matlab.net.http.*
            import matlab.net.http.field.*

            % Try API v2 endpoint (HTTPS)
            urlV2 = "https://" + app.hueIPAddress + "/clip/v2/resource/bridge";

            try
                req = RequestMessage('get', HeaderField("hue-application-key", app.appID));
                opts = HTTPOptions( ...
                    'VerifyServerName', false, ...
                    'CertificateFilename', '' ...   % <‑‑ THIS disables CA validation
                    );

                resp = req.send(urlV2, opts);

                if resp.StatusCode == matlab.net.http.StatusCode.OK
                    apiVersion = 2;
                    return
                end
            catch
                % ignore, fall through to v1
            end

            % Try API v1 endpoint (HTTP)
            urlV1 = "http://" + app.hueIPAddress + "/api/" + app.appID + "/config";

            try
                cfg = webread(urlV1);
                if isfield(cfg, 'bridgeid')
                    apiVersion = 1;
                    return
                end
            catch
                % ignore
            end

            error("Unable to detect Hue API version");

        end

        %%%
        %%% Builds BOTH v1→v2 and v2→v1 maps for all Hue resource types.
        %%% Works on Bridge Pro (API v2). Returns a struct with:
        %%%  map.v1_to_v2.<type>(v1ID) = v2UUID
        %%%  map.v2_to_v1.<type>(v2UUID) = v1ID
        %%%

        function map = buildHueIdMaps(app)
            % To invoke:  map = buildHueIdMaps(app);
            % Usage:
            % Convert  v1 lightno → v2 UUID
            % uuid = map.v1_to_v2.lights('/lights/12');
            %
            % Convert v2 UUID → v1 lightno
            % v1ightno = map.v2_to_v1.lights(uuid);

            import matlab.net.http.*
            import matlab.net.http.field.*
            disp(app.hueIPAddress);
            url = "https://" + app.hueIPAddress + "/clip/v2/resource";

            header = HeaderField("hue-application-key", app.appID);
            req = RequestMessage('get', header);
            opts = HTTPOptions( ...
                'VerifyServerName', false, ...
                'CertificateFilename', '' ...   % <‑‑ THIS disables CA validation
                );


            resp = req.send(url, opts);

            if resp.StatusCode ~= matlab.net.http.StatusCode.OK
                error("Failed to query Bridge Pro resource list");
            end

            data = resp.Body.Data.data;

            % Initialise mapping containers
            types = ["lights","groups","scenes","sensors","all"];
            for t = types
                map.v1_to_v2.(t) = containers.Map('KeyType','char','ValueType','char');
                map.v2_to_v1.(t) = containers.Map('KeyType','char','ValueType','char');
            end

            % Process each resource entry
            for i = 1:numel(data)
                entry = data(i);

                if ~isfield(entry, 'id_v1')
                    continue
                end

                v1 = char(entry.id_v1);   % e.g. "/lights/12"
                v2 = char(entry.id);      % UUID

                % Store in global maps
                map.v1_to_v2.all(v1) = v2;
                map.v2_to_v1.all(v2) = v1;

                % Categorise by resource type
                switch entry.type
                    case "light"
                        map.v1_to_v2.lights(v1) = v2;
                        map.v2_to_v1.lights(v2) = v1;

                    case {"room","zone"}
                        map.v1_to_v2.groups(v1) = v2;
                        map.v2_to_v1.groups(v2) = v1;

                    case {"motion","temperature","light_level","device"}
                        map.v1_to_v2.sensors(v1) = v2;
                        map.v2_to_v1.sensors(v2) = v1;

                    case "scene"
                        map.v1_to_v2.scenes(v1) = v2;
                        map.v2_to_v1.scenes(v2) = v1;
                end
            end
        end

        %%% >>>
        % ✔ Full v1/v2 auto‑switching for:
        % lights
        % groups/rooms
        % scenes
        % sensors
        % automations
        % ✔ Automatic ID translation (v1 ↔ v2)
        % ✔ Unified API layer
        % ✔ UI stays unchanged
        % ✔ Bridge‑agnostic control
        % ✔ Future‑proof for your migration scripts
        %%% <<<

        function setGroupState(app, id, state)

            if app.vAPI == 1
                setGroupStateV1(app, id, state);
            else
                key = sprintf('/groups/%d', id);
                uuid = app.idMap.v1_to_v2.groups(key);
                setGroupStateV2(app, uuid, state);
            end
        end

        function setGroupStateV1(app, groupID, state)
            url = sprintf('http://%s/api/%s/groups/%d/action', ...
                app.hueIPAddress, app.appID, groupID);
            webwrite(url, state, weboptions('MediaType','application/json','RequestMethod','put'));
        end

        function setGroupStateV2(app, uuid, state)

            import matlab.net.http.*
            import matlab.net.http.field.*

            url = "https://" + app.hueIPAddress + "/clip/v2/resource/room/" + uuid;

            % Convert v1-style state to v2-style
            body = convertStateV1toV2(app,state);

            req = RequestMessage('patch', HeaderField("hue-application-key", app.appID), body);
            opts = HTTPOptions( ...
                'VerifyServerName', false, ...
                'CertificateFilename', '' ...   % <‑‑ THIS disables CA validation
                );

            req.send(url, opts);
        end

        function activateScene(app, id)

            if app.vAPI == 1
                activateSceneV1(app, id);
            else
                key = sprintf('/scenes/%s', id);
                uuid = app.idMap.v1_to_v2.scenes(key);
                activateSceneV2(app, uuid);
            end
        end

        function activateSceneV1(app, sceneID)
            url = sprintf('http://%s/api/%s/groups/0/action', app.hueIPAddress, app.appID);
            body = struct('scene', sceneID);
            webwrite(url, body, weboptions('MediaType','application/json','RequestMethod','put'));
        end

        function activateSceneV2(app, uuid)

            import matlab.net.http.*
            import matlab.net.http.field.*

            url = "https://" + app.hueIPAddress + "/clip/v2/resource/scene/" + uuid;

            body = struct('recall', struct('action', 'active'));

            req = RequestMessage('put', HeaderField("hue-application-key", app.appID), body);
            opts = HTTPOptions( ...
                'VerifyServerName', false, ...
                'CertificateFilename', '' ...   % <‑‑ THIS disables CA validation
                );

            req.send(url, opts);
        end

        function data = readSensor(app, id)

            if app.vAPI == 1
                data = readSensorV1(app, id);
            else
                key = sprintf('/sensors/%d', id);
                uuid = app.idMap.v1_to_v2.sensors(key);
                data = readSensorV2(app, uuid);
            end
        end

        function data = readSensorV1(app, sensorID)
            url = sprintf('http://%s/api/%s/sensors/%d', app.hueIPAddress, app.appID, sensorID);
            data = webread(url);
        end

        function data = readSensorV2(app, uuid)

            import matlab.net.http.*
            import matlab.net.http.field.*

            url = "https://" + app.hueIPAddress + "/clip/v2/resource/sensor/" + uuid;

            req = RequestMessage('get', HeaderField("hue-application-key", app.appID));
            opts = HTTPOptions( ...
                'VerifyServerName', false, ...
                'CertificateFilename', '' ...   % <‑‑ THIS disables CA validation
                );

            resp = req.send(url, opts);

            data = resp.Body.Data.data;
        end

        function runAutomation(app, id)

            if app.vAPI == 1
                error("Automations do not exist on API v1");
            else
                uuid = id; % automations are v2-only
                runAutomationV2(app, uuid);
            end
        end

        function runAutomationV2(app, uuid)

            import matlab.net.http.*
            import matlab.net.http.field.*

            url = "https://" + app.hueIPAddress + "/clip/v2/resource/automation/" + uuid;

            body = struct('enabled', true);

            req = RequestMessage('patch', HeaderField("hue-application-key", app.appID), body);
            opts = HTTPOptions('VerifyServerName', false);
            req.send(url, opts);
        end

        function out = convertStateV1toV2(~,state)

            out = struct;

            if isfield(state, 'on')
                out.on = struct('on', state.on);
            end

            if isfield(state, 'bri')
                out.dimming = struct('brightness', state.bri / 2.54);
            end

            if isfield(state, 'ct')
                out.color_temperature = struct('mirek', state.ct);
            end

            if isfield(state, 'xy')
                out.color = struct('xy', struct('x', state.xy(1), 'y', state.xy(2)));
            end
        end

        %%%
        %%% Mock API constructor and methods to call backend
        %%%

        function obj = HueBridgeV2(~,varargin)
            p = inputParser;
            addParameter(p, "Mode", "real");
            addParameter(p, "BaseUrl", "");
            addParameter(p, "AppKey", "");
            parse(p, varargin{:});

            obj.Mode = p.Results.Mode;

            if obj.Mode == "mock"
                obj.HttpBackend = MockHueBackend();
            else
                obj.HttpBackend = RealHueBackend(obj.BaseUrl, obj.AppKey);
            end
        end

        function test_mock_backend(~)

            bridge = HueBridgeV2("Mode","mock"); % Do NOT use 'app' as first parameter!

            disp("=== Lights ===")
            lights = bridge.getResources("light");
            disp(lights)

            disp("=== Update light ===")
            updated = bridge.updateResource("light","light-1", ...
                struct("on",struct("on",true)));
            disp(updated)

            disp("=== Create scene ===")
            newScene = bridge.createResource("scene", struct( ...
                "metadata", struct("name","Test Scene"), ...
                "group", struct("rid","room-1","rtype","room")));
            disp(newScene)

            disp("=== Delete scene ===")
            bridge.deleteResource("scene", newScene.id)

            disp("=== All mock tests passed ===")
        end

        %%%
        %%%
        %%%

        function tf = deviceHasSensorServices(~,device)
            sensorTypes = ["motion","light_level","temperature","device_power","button","contact"];
            rtypes = string({device.services.rtype});
            tf = any(ismember(rtypes, sensorTypes));
        end


        %%%
        %%% Test for valid IP Addres using API v2
        %%%

        function tf = isValidHueV2IP(app, ip)
            import matlab.net.http.*
            import matlab.net.http.field.*

            url = "https://" + ip + "/clip/v2/resource";

            req = RequestMessage('get', ...
                HeaderField('hue-application-key', app.appID));

            opts = HTTPOptions( ...
                'VerifyServerName', false, ...   % ignore hostname mismatch
                'CertificateFilename', '' ...    % ignore untrusted root
                );

            try
                resp = req.send(url, opts);

                if resp.StatusCode ~= matlab.net.http.StatusCode.OK
                    disp("Bridge returned: " + string(resp.StatusCode))
                    tf = false;
                    return
                end

            catch ME
                disp("Error contacting bridge:")
                disp(ME.message)
                tf = false;
                return
            end

            tf = true;
        end


        %%%
        %%% Check which Bridges are connected
        %%%


        function [bridges, errCode] = discoverV2Bridges(~)
            %DISCOVERV2BRIDGES  Return only Philips Hue Bridge v2 devices (BSB002)
            %
            %   bridges = discoverV2Bridges()
            %
            %   Uses the Hue discovery service to find all bridges on the LAN,
            %   then filters out anything that is not a v2 bridge.
            %
            %   This prevents accidental pairing attempts with the Hue Bridge Pro,
            %   which enforces HTTPS and causes certificate errors in MATLAB.

            % Query Hue discovery service

            errCode = 0;
            try
                allBridges = webread('https://discovery.meethue.com');
            catch
                warning('Unable to reach Hue discovery service.');
                bridges = [];
                return;
            end

            bridges = [];

            % Loop through discovered bridges
            for k = 1:numel(allBridges)
                ip = allBridges(k).internalipaddress;

                % Try to read the bridge description
                try
                    desc = webread(['http://' ip '/description.xml']);
                catch MSG
                    % Skip unreachable or HTTPS-only bridges
                    % Cater for lack of internet..
                    if contains(MSG.message,'not connect to server') || contains(MSG.message,'not access server')
                        errCode = 999;
                        return;
                    end
                    continue;
                end

                % Check for v2 model ID
                if contains(desc, 'BSB002')   % Hue Bridge v2 ('BSB003' is Bridge Pro)
                    bridges = [bridges; allBridges(k)];
                end
            end

        end

        %%%
        %%% Resource mapping for API v2 data
        %%%

        function s = getService(~, device, rtype, resourceMap)
            % Returns the full service struct for the given rtype
            % or [] if not found.

            s = [];

            for i = 1:numel(device.services)
                svc = device.services(i);

                if svc.rtype == rtype
                    rid = svc.rid;

                    if isKey(resourceMap, rid)
                        s = resourceMap(rid);
                    end

                    return
                end
            end
        end

        %%%
        %%% api v2 to api v1 converters
        %%%

        % --------------------------------------------------------------
        % Load all v2 resources and split into categories
        % --------------------------------------------------------------
        function loadAllResources(app)

            import matlab.net.http.*

            opts = HTTPOptions('VerifyServerName', false, ...
                'CertificateFilename', '');
            url = "https://" + app.hueIPAddress + "/clip/v2/resource";
            req = RequestMessage('get', ...
                HeaderField('hue-application-key', app.appKey));

            resp = req.send(url, opts);

            data = resp.Body.Data.data;

            app.allResources = data;
            app.resourceMap = app.buildResourceMap(data);

            % Split resources by type
            app.devices       = app.filterByType("device");
            app.groups        = app.filterByType(["room","zone"]);
            app.scenes        = app.filterByType("scene");
            app.rules         = app.filterByType("behavior_script");
            app.schedules     = app.filterByType("behavior_instance");
            app.capabilities  = app.filterByType("entertainment_configuration");
            app.resourcelinks = app.filterByType("resource_link");
        end

        % --------------------------------------------------------------
        % Build RID → resource struct map
        % --------------------------------------------------------------
        function map = buildResourceMap(app, data)
            map = containers.Map;
            for i = 1:numel(data)
                r = data{i};
                map(r.id) = r;
            end
        end

        % --------------------------------------------------------------
        % Filter resources by type
        % --------------------------------------------------------------
        function out = filterByType(app, types)
            out = struct([]);
            idx = 1;

            for i = 1:numel(app.allResources)
                r = app.allResources{i};
                if any(r.type == types)
                    out(idx) = r;
                    idx = idx + 1;
                end
            end
        end

        % --------------------------------------------------------------
        % Public v1-style API
        % --------------------------------------------------------------
        function out = lights(app)
            out = convertV2LightsToV1(app, app.devices, app.resourceMap);
        end

        function out = groups(app)
            out = convertV2GroupsToV1(app, app.groups, app.resourceMap);
        end

        function out = scenes(app)
            out = convertV2ScenesToV1(app, app.scenes, app.resourceMap);
        end

        function out = sensors(app)
            out = convertV2SensorsToV1(app, app.devices, app.resourceMap);
        end

        function out = rules(app)
            out = convertV2RulesToV1(app, app.rules, app.resourceMap);
        end

        function out = schedules(app)
            out = convertV2SchedulesToV1(app, app.schedules, app.resourceMap);
        end

        function out = capabilities(app)
            out = convertV2CapabilitiesToV1(app, app.capabilities);
        end

        function out = resourcelinks(app)
            out = convertV2ResourceLinksToV1(app, app.resourcelinks);
        end

        %%%
        %%% API v2 to v1 converters
        %%%

        function v1 = convertV2LightsToV1(app, devices, resourceMap)

            v1 = struct([]);
            idx = 1;

            for d = 1:numel(devices)
                device = devices(d);

                % Must have a light service
                lightSvc = app.getService(device, "light", resourceMap);
                if isempty(lightSvc)
                    continue
                end

                v1(idx).name = device.metadata.name;
                v1(idx).uniqueid = device.id;
                v1(idx).type = "Extended color light";

                % ON/OFF
                onSvc = app.getService(device, "on", resourceMap);
                if ~isempty(onSvc)
                    v1(idx).state.on = onSvc.on.on;
                else
                    v1(idx).state.on = false;
                end

                % Brightness
                dimSvc = app.getService(device, "dimming", resourceMap);
                if ~isempty(dimSvc)
                    v1(idx).state.bri = round(dimSvc.dimming.brightness * 2.54);
                else
                    v1(idx).state.bri = 0;
                end

                % Color Temperature
                ctSvc = app.getService(device, "color_temperature", resourceMap);
                if ~isempty(ctSvc)
                    v1(idx).state.ct = ctSvc.color_temperature.mirek;
                end

                % XY Color
                colSvc = app.getService(device, "color", resourceMap);
                if ~isempty(colSvc)
                    v1(idx).state.xy = colSvc.color.xy;
                end

                % Reachable
                zig = app.getService(device, "zigbee_connectivity", resourceMap);
                if ~isempty(zig)
                    v1(idx).state.reachable = (zig.status == "connected");
                else
                    v1(idx).state.reachable = true;
                end

                idx = idx + 1;
            end
        end
        function v1 = convertV2GroupsToV1(~, groups, ~)

            v1 = struct([]);
            idx = 1;

            for g = 1:numel(groups)
                grp = groups(g);

                v1(idx).name = grp.metadata.name;
                v1(idx).type = grp.type;
                v1(idx).uniqueid = grp.id;

                v1(idx).lights = string([]);

                for i = 1:numel(grp.children)
                    child = grp.children(i);
                    if child.rtype == "device"
                        v1(idx).lights(end+1) = child.rid;
                    end
                end

                idx = idx + 1;
            end
        end
        function v1 = convertV2ScenesToV1(~, scenes, ~)

            v1 = struct([]);
            idx = 1;

            for s = 1:numel(scenes)
                sc = scenes(s);

                v1(idx).name = sc.metadata.name;
                v1(idx).type = "LightScene";
                v1(idx).uniqueid = sc.id;

                v1(idx).lights = string([]);
                v1(idx).lightstates = struct();

                for a = 1:numel(sc.actions)
                    act = sc.actions(a);
                    rid = act.target.rid;

                    v1(idx).lights(end+1) = rid;
                    v1(idx).lightstates.(rid) = act.action;
                end

                idx = idx + 1;
            end
        end
        function v1 = convertV2SensorsToV1(app, devices, resourceMap)

            v1 = struct([]);
            idx = 1;

            for d = 1:numel(devices)
                device = devices(d);

                state = app.getSensorState(device, resourceMap);

                % Motion
                if ~isempty(state.motion)
                    v1(idx).type = "ZLLPresence";
                    v1(idx).name = device.metadata.name + " motion";
                    v1(idx).state.presence = state.motion;
                    v1(idx).state.lastupdated = state.lastupdated;
                    v1(idx).uniqueid = device.id;
                    idx = idx + 1;
                end

                % Temperature
                if ~isnan(state.temperature)
                    v1(idx).type = "ZLLTemperature";
                    v1(idx).name = device.metadata.name + " temperature";
                    v1(idx).state.temperature = state.temperature;
                    v1(idx).state.lastupdated = state.lastupdated;
                    v1(idx).uniqueid = device.id;
                    idx = idx + 1;
                end

                % Light Level
                if ~isnan(state.lightlevel)
                    v1(idx).type = "ZLLLightLevel";
                    v1(idx).name = device.metadata.name + " light level";
                    v1(idx).state.lightlevel = state.lightlevel;
                    v1(idx).state.lastupdated = state.lastupdated;
                    v1(idx).uniqueid = device.id;
                    idx = idx + 1;
                end

                % Battery
                if ~isnan(state.battery)
                    v1(idx).type = "ZLLBattery";
                    v1(idx).name = device.metadata.name + " battery";
                    v1(idx).state.battery = state.battery;
                    v1(idx).uniqueid = device.id;
                    idx = idx + 1;
                end

                % Contact
                if ~isempty(state.contact)
                    v1(idx).type = "ZLLContact";
                    v1(idx).name = device.metadata.name + " contact";
                    v1(idx).state.open = state.contact;
                    v1(idx).uniqueid = device.id;
                    idx = idx + 1;
                end

                % Tamper
                if ~isempty(state.tamper)
                    v1(idx).type = "ZLLTamper";
                    v1(idx).name = device.metadata.name + " tamper";
                    v1(idx).state.tamper = state.tamper;
                    v1(idx).uniqueid = device.id;
                    idx = idx + 1;
                end

                % Button
                if ~isempty(state.button)
                    v1(idx).type = "ZLLSwitch";
                    v1(idx).name = device.metadata.name + " button";
                    v1(idx).state.buttonevent = state.button;
                    v1(idx).uniqueid = device.id;
                    idx = idx + 1;
                end

                % Rotary
                if ~isempty(state.rotary)
                    v1(idx).type = "ZLLRelativeRotary";
                    v1(idx).name = device.metadata.name + " rotary";
                    v1(idx).state.rotaryevent = state.rotary;
                    v1(idx).uniqueid = device.id;
                    idx = idx + 1;
                end
            end
        end
        function v1 = convertV2RulesToV1(~, rules, ~)

            v1 = struct([]);
            idx = 1;

            for r = 1:numel(rules)
                scr = rules(r);

                v1(idx).name = scr.metadata.name;
                v1(idx).uniqueid = scr.id;
                v1(idx).status = "enabled";

                v1(idx).conditions = scr.configuration.trigger;
                v1(idx).actions    = scr.configuration.action;

                idx = idx + 1;
            end
        end
        function v1 = convertV2SchedulesToV1(~, schedules, ~)

            v1 = struct([]);
            idx = 1;

            for s = 1:numel(schedules)
                inst = schedules(s);

                v1(idx).name = inst.metadata.name;
                v1(idx).uniqueid = inst.id;

                v1(idx).time = inst.configuration.schedule_time;
                v1(idx).command = inst.configuration.action;

                idx = idx + 1;
            end
        end
        function v1 = convertV2CapabilitiesToV1(~, capabilities)

            v1 = struct([]);
            idx = 1;

            for c = 1:numel(capabilities)
                cap = capabilities(c);

                v1(idx).name = cap.metadata.name;
                v1(idx).uniqueid = cap.id;
                v1(idx).capabilities = cap;

                idx = idx + 1;
            end
        end
        function v1 = convertV2ResourceLinksToV1(~, links)

            v1 = struct([]);
            idx = 1;

            for l = 1:numel(links)
                link = links(l);

                v1(idx).name = link.metadata.name;
                v1(idx).uniqueid = link.id;
                v1(idx).links = link.children;

                idx = idx + 1;
            end
        end
    end

    %%%
    %%%
    %%%


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc

            if app.vAPI == 2
                app.Bridge = HueBridgeV2("Mode","mock");
                disp(app.Bridge);
                lights = app.Bridge.getResources("light");
                disp(lights)
                test_mock_backend(app);
            end

            if app.restoreWindowPos
                restoreWindowState(app);
            end
            app.lastRefresh = datetime("now");
            % Hide Colour column (9) in LightsTable
            % w = app.LightsTable.ColumnWidth;
            % w{9} = 0;
            % app.LightsTable.ColumnWidth = w;
            % Show Colour column in LightsTable
            % w = app.LightsTable.ColumnWidth;
            % w{9} = 'auto';            % or a numeric width, e.g. 120
            % app.LightsTable.ColumnWidth = w;

            %
            % Determine folder from which the app was launched
            %
            if isempty (string(app.appFolder))
                fullPath = mfilename('fullpath');
                app.appFolder = fileparts(which(fullPath));
                fprintf('app.appFolder = %s.\n', app.appFolder);
            end
            if ispc
                htmlFolder = [app.appFolder '\HTML'];
                disp(htmlFolder);
            else
                htmlFolder = [app.appFolder '/HTML'];
            end
            if ~exist(htmlFolder, 'dir')
                error ('HTML folder <%s> is missing.\n', htmlFolder);
            else
                fprintf('HTML folder <%s> found.\n', htmlFolder);
            end

            % Make connections to external HTML sources
            % HTML is saved in the HTML folder..
            if ispc
                addpath (genpath(strcat(app.appFolder, '\HTML')));
                addpath (genpath(strcat(app.appFolder, '\Classes')));
            else
                addpath (genpath(strcat(app.appFolder, '/HTML')));
                addpath (genpath(strcat(app.appFolder, '/Classes')));
            end

            app.HTMLTempTrend.HTMLSource =  fullfile(app.appFolder, 'HTML', 'blinkTextFlexible.html');
            app.HTMLLowBatMsg.HTMLSource = fullfile(app.appFolder, 'HTML','pulseTextHue.html');
            app.HTMLLightsOn.HTMLSource = fullfile(app.appFolder, 'HTML','blinkTextFlexible.html');
            app.HTMLLastMotion.HTMLSource = fullfile(app.appFolder, 'HTML','blinkTextFlexible.html');
            app.HTMLLowBatDevs.HTMLSource = fullfile(app.appFolder, 'HTML','blinkTextFlexible.html');
            app.HTMLUnreachableLights.HTMLSource = fullfile(app.appFolder, 'HTML','blinkTextFlexible.html');

            % Pulse image
            app.Pulse = pulsingImage(app.HueLogo, 'huelogo.png');
            app.Pulse.HtmlComp.Data.size = struct('width', 145, 'height', 123);
            app.Pulse.HtmlComp.Position = [1 1 145 123];
            app.Pulse.setSpeed(10);
            app.Pulse.setOn(true);

            wID = 'MATLAB:ui:uihtml:ErrorOccuredInHTMLSource';
            warning('off',wID);

            %%% [msgStr,warnId] = lastwarn;
            % if contains(msgStr, 'HTMLSource')
            %     fprintf('\n*** Disabling spurious HTMLSource warnings:');
            %     warning('off',wID);
            %     fprintf('\n*** Warning ID:  %s DISABLED. ***', warnId);
            %     fprintf('\n*** Warning Msg (for info): %s ***\n',msgStr);
            % end
            %
            %%% Optional Pulse image settings
            % Pulse off:
            % app.Pulse.setOn(false);
            % Pulse on:
            % app.Pulse.setOn(true);
            % Pulse speed:
            % app.Pulse.setSpeed(10);
            % New icon:
            % app.Pulse.setImage('newicon.png');

            app.dataRepository = app.appFolder; % CURRENT directory (* NOT necessarily the directory from which the App was launched *)

            switch app.vAPI
                case 1
                    bridgeVersion = 'BridgeV2';
                case 2
                    bridgeVersion = 'BridgePro';
                otherwise
                    bridgeVersion = '';
            end
            if ispc
                repositoryName = ['\HueData' bridgeVersion];
            else
                repositoryName = ['/HueData' bridgeVersion];
            end
            fprintf('\n%s starting Up in Directory: %s\nLooking for existing Hue Data Repository %s...', mfilename, app.appFolder, repositoryName);
            %%%
            %%% First check whether the current directory is already the data repository..
            %%%
            if ~contains(app.dataRepository,repositoryName)
                app.dataRepository = [app.dataRepository repositoryName];
                % Now check if there is a data repository sub-directory...
                if ~exist(app.dataRepository, 'dir')
                    mkdir(app.dataRepository);
                    fprintf('Not found.  Created new Hue Data Repository: %s\n', app.dataRepository);
                else
                    fprintf('Found.\n');
                end
            else
                fprintf('Found.\n');
            end
            fileName = 'userNameHue.mat';
            fprintf('Looking for file: %s in %s...', fileName, app.dataRepository);
            usernameFile = fullfile(app.dataRepository, fileName);
            % Check if file exists
            if ~exist(usernameFile, 'file')
                fprintf('not found.  Attempting to copy from main Hue directory..');
                if ispc
                    sourceDir = 'C:\Users\spfol\OneDrive\Matlab2025\Current_r2025\Hue';
                    sourceFile = fullfile(sourceDir, fileName);
                else
                    sourceDir = '/Users/stephenfolmer/Library/CloudStorage/OneDrive-Personal/Matlab2025/Current_r2025/Hue'; % directorty containing master of userNameHue.mat
                    sourceFile = fullfile(sourceDir, fileName);
                end
                targetFile = fullfile(app.dataRepository, fileName);
                % Check if file exists
                if exist(sourceFile, 'file')
                    % Copy the file
                    copyfile(sourceFile, targetFile);
                    fprintf('Source File copied to: %s\n', targetFile);
                else
                    fprintf('\n** Master Source File not found: %s **\n', sourceFile);
                end
            else
                fprintf('Found.\n');
            end

            errCode = getStats (app, 'startup');
            if errCode == 0
                errCode = getStats (app, 'refresh');
            end
            dt = datetime('now', 'Format', 'dd/MM/yy HH:mm:ss');
            strDt = string(dt);
            app.DiagnosticsTimeTextArea.Value = strDt;
            if errCode == 0
                if ~ app.offlineMode
                    app.DiagnosticsMsgTextArea.Value = append('Bridge data loaded. ',app.restartMsg);
                else
                    app.DiagnosticsMsgTextArea.Value = append('Previously saved Bridge data loaded. ',app.restartMsg);
                end
                app.successfulStart = true;
                startTimer(app);
            elseif ~ app.offlineMode
                app.DiagnosticsMsgTextArea.Value = [app.DiagnosticsMsgTextArea.Value '  Start-up failed.'];
                lastDiagMsg = app.DiagnosticsMsgTextArea.Value;
                save (app.diagFileName,"lastDiagMsg");
                app.successfulStart = false;
            end


        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            dt = datetime('now', 'Format', 'dd/MM/yy HH:mm:ss');
            strDt = string(dt);
            restartDetails = append('Successfully terminated on ',strDt, '.');
            if ispc
                save([app.dataRepository '\hueAppDiagnostics'],"restartDetails");
            else
                save([app.dataRepository '/hueAppDiagnostics'],"restartDetails");
            end
            warning('on', 'all');
            saveWindowState(app);
            stopTimer(app);
            closereq;
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            % If not yet successfully started (e.g. lack of internet) re-initiate startup processing..
            if ~app.successfulStart
                startupFcn(app);
            else
                refreshDetails(app);
            end
        end

        % Cell selection callback: LightsTable
        function LightsTableCellSelection(app, event)
            pause(0.01);  % 10ms delay allows CellEditCallback to finish
            dt = datetime('now', 'Format', 'dd/MM/yy HH:mm:ss');
            strDt = string(dt);
            app.DiagnosticsTimeTextArea.Value = strDt;
            app.cellSelIndices = event.Indices;
            noLights = size(app.LightsTable.Data);
            switch app.cellSelIndices(1,2)
                case 8 % brightness
                    app.DiagnosticsMsgTextArea.Value = newBrightness(app, event);
                    return;
                case 9 % colour
                    app.DiagnosticsMsgTextArea.Value = "Colour selected.";
                otherwise % toggle
                    drawnow;
                    if app.cellSelIndices(1,2) == 1 % Group selected
                        if app.prevEventName == "KeyPress" && app.editGroup == app.LightsTable.Data{app.cellSelIndices(1,1),1}
                            % brightness changed - only update brightness; don't toggle group lights
                            if app.xyColour(1,1) == 0
                                groupBrightness(app, noLights);
                                app.DiagnosticsMsgTextArea.Value = strcat(app.LightsTable.Data{app.cellSelIndices(1,1),1}, " brightness changed.");
                            else
                                coloursChanged = groupColour(app, noLights);
                                if coloursChanged > 0
                                    app.DiagnosticsMsgTextArea.Value = strcat(app.LightsTable.Data{app.cellSelIndices(1,1),1}, " colour set to ", app.newColr,".");
                                else
                                    app.DiagnosticsMsgTextArea.Value = append("No colour lights found in ",app.LightsTable.Data{app.cellSelIndices(1,1),1}, ".  Change ignored.");
                                end
                            end
                            app.editGroup = 'none'; % reset so that the same group will toggle if selected next
                        else
                            selGrp = app.LightsTable.Data{app.cellSelIndices(1,1),1};
                            grpType = app.LightsTable.Data{app.cellSelIndices(1,1),2};
                            for i = 1:noLights(1,1)
                                if app.LightsTable.Data{i,1} == selGrp ...
                                        && app.LightsTable.Data{i,2} == grpType
                                    numLight = app.LightsTable.Data{i,3};
                                    onOff = toggleLights(app, numLight, i, true);
                                    for j = 1:noLights(1,1)
                                        if app.LightsTable.Data{j,3} == numLight
                                            app.LightsTable.Data{j,7} = onOff;
                                        end
                                    end
                                end
                            end
                            app.DiagnosticsMsgTextArea.Value = strcat(app.LightsTable.Data{app.cellSelIndices(1,1),1}, " lights toggled.");
                        end
                    else
                        if app.prevEventName ~= "KeyPress" % ignore toggle if brightness was just updated
                            numLight = app.LightsTable.Data{app.cellSelIndices(1,1),3};
                            onOff = toggleLights(app, numLight, app.cellSelIndices(1,1), true);
                            for j = 1:noLights(1,1)
                                if app.LightsTable.Data{j,3} == numLight
                                    app.LightsTable.Data{j,7} = onOff;
                                end
                            end
                            app.DiagnosticsMsgTextArea.Value = strcat(app.LightsTable.Data{app.cellSelIndices(1,1),4}, " light toggled.");
                        end
                    end
                    highlightOnLights(app);
                    app.prevEventName = event.EventName;
                    app.xyColour = [0,0];
                    updateONGroupsRooms(app);
            end

        end

        % Button pushed function: ToggleALLLightsButton
        function ToggleALLLightsButtonPushed(app, event)
            app.DiagnosticsMsgTextArea.Value = 'Toggling ALL Lights..';
            drawnow;
            noLights = size(app.LightsTable.Data);
            for i = 1:noLights(1,1)
                numLight = app.LightsTable.Data{i,3};
                toggleLights(app, numLight, i, false);
            end
            dt = datetime('now', 'Format', 'dd/MM/yy HH:mm:ss');
            strDt = string(dt);
            app.DiagnosticsTimeTextArea.Value = strDt;
            app.DiagnosticsMsgTextArea.Value = 'ALL lights toggled.';
            highlightOnLights(app);
            app.prevEventName = event.EventName;
            updateONGroupsRooms(app);
        end

        % Button pushed function: AllLightsOn
        function AllLightsOnButtonPushed(app, event)
            app.DiagnosticsMsgTextArea.Value = 'Switching ALL Lights ON..';
            drawnow;
            options = weboptions('MediaType', 'application/json', 'RequestMethod', 'put');
            state = struct('on',true);
            noLights = size(app.LightsTable.Data);
            for i = 1:noLights(1,1)
                if app.LightsTable.Data{i,7} == 0
                    app.LightsTable.Data{i,7} = 1;
                    if app.LightsTable.Data{i,2} == "Room"
                        numLight = app.LightsTable.Data{i,3};
                        wrURL = sprintf('http://%s/api/%s/lights/%d/state', app.hueIPAddress,app.appID,numLight);
                        webwrite(wrURL, state, options);
                        app.lightsOn = app.lightsOn + 1;
                    end
                end
            end
            dt = datetime('now', 'Format', 'dd/MM/yy HH:mm:ss');
            strDt = string(dt);
            app.DiagnosticsTimeTextArea.Value = strDt;
            app.DiagnosticsMsgTextArea.Value = 'ALL lights ON.';
            highlightOnLights(app);
            app.prevEventName = event.EventName;
            app.ONLights.Value = app.lightsOn;
            if app.lightsOn == 1
                app.LightsOnLabel.Text = 'Light';
            else
                app.LightsOnLabel.Text = 'Lights';
            end
            updateONGroupsRooms(app);
            blinkLightsOn(app);
            % fprintf('\n AllLightson function: app.lightsOn %d', app.lightsOn);
        end

        % Button pushed function: AllLightsOff
        function AllLightsOffButtonPushed(app, event)
            app.DiagnosticsMsgTextArea.Value = 'Switching ALL Lights OFF..';
            drawnow;
            options = weboptions('MediaType', 'application/json', 'RequestMethod', 'put');
            state = struct('on',false);
            noLights = size(app.LightsTable.Data);
            for i = 1:noLights(1,1)
                if app.LightsTable.Data{i,7} == 1
                    app.LightsTable.Data{i,7} = 0;
                    if app.LightsTable.Data{i,2} == "Room"
                        numLight = app.LightsTable.Data{i,3};
                        wrURL = sprintf('http://%s/api/%s/lights/%d/state', app.hueIPAddress,app.appID,numLight);
                        webwrite(wrURL, state, options);
                    end
                end
            end
            dt = datetime('now', 'Format', 'dd/MM/yy HH:mm:ss');
            strDt = string(dt);
            app.DiagnosticsTimeTextArea.Value = strDt;
            app.DiagnosticsMsgTextArea.Value = 'ALL lights OFF.';
            highlightOnLights(app);
            app.prevEventName = event.EventName;
            app.ONLights.Value = 0;
            updateONGroupsRooms(app);
            app.lightsOn = 0;
            blinkLightsOn(app);
        end

        % Cell edit callback: LightsTable
        function LightsTableCellEdit(app, event)

            drawnow;
            app.cellSelIndices = event.Indices;
            app.editGroup = app.LightsTable.Data{event.Indices(1,1),1};
            dt = datetime('now', 'Format', 'dd/MM/yy HH:mm:ss');
            strDt = string(dt);
            app.DiagnosticsTimeTextArea.Value = strDt;
            app.xyColour = [0,0];
            app.newColr = '';
            if app.cellSelIndices(1,2) == 9 % Colour edited
                app.colBri = app.LightsTable.Data{event.Indices(1,1),8};
                switch upper(event.EditData)
                    case 'B'
                        app.newColr = 'blue';
                        app.xyColour= [0.15, 0.06];
                    case 'C'
                        app.newColr = 'cyan';
                        app.xyColour= [0.17, 0.34];
                    case 'G'
                        app.newColr = 'green';
                        app.xyColour= [0.17, 0.7];
                    case 'Y'
                        app.newColr = 'yellow';
                        app.xyColour= [0.44, 0.51];
                    case 'O'
                        app.newColr = 'orange';
                        app.xyColour= [0.56, 0.42];
                    case 'R'
                        app.newColr = 'red';
                        app.xyColour= [0.7, 0.3];
                    case 'P'
                        app.newColr = 'purple';
                        app.xyColour= [0.25, 0.1];
                    case 'M'
                        app.newColr = 'magenta';
                        app.xyColour= [0.38, 0.17];
                    case 'W'
                        app.newColr = 'white';
                        app.xyColour= [0.31, 0.33];
                    otherwise
                        app.LightsTable.Data{event.Indices(1,1),9} = event.PreviousData; % restore previous colour name
                        app.DiagnosticsMsgTextArea.Value = 'Invalid colour.  Must be: b(lue), c(yan), g(reen), y(ellow), o(range), r(ed), p(urple), m(agenta) or w(hite).';
                        return;
                end
                app.DiagnosticsMsgTextArea.Value = newColour (app, event);
            else % Brightness edited
                if isnan(event.NewData)
                    app.LightsTable.Data{event.Indices(1,1),8} = event.PreviousData; % restore previous numerical value
                    app.DiagnosticsMsgTextArea.Value = 'Brightness must be a numerical value from 1 to 100.';
                    return;
                else
                    app.DiagnosticsMsgTextArea.Value = newBrightness (app, event);
                end
            end
        end

        % Selection changed function: LightsTable
        function LightsTableSelectionChanged(app, event)
            app.prevCellSel = event.PreviousSelection;
            if isempty(app.prevCellSel)
                app.prevCellSel = [0 0];
            end
        end

        % Key press function: LightsTable
        function LightsTableKeyPressed(app, event)
            app.lastKey = event.Key;
            switch app.lastKey
                case "return"
                    app.prevEventName = 'none';
                    app.editGroup = '';
                case "tab"
                    app.prevEventName = 'none';
                    app.editGroup = '';
                otherwise
                    app.prevEventName = event.EventName;
            end
        end

        % Key press function: HueDashboardv53UIFigure
        function HueDashboardv53UIFigureKeyPress(app, event)
            app.lastKey = event.Key;
        end

        % Value changed function: lightsOnRoomsOnlyCheckbox
        function lightOnRoomsOnly(app, event)

            if app.lightsOnRoomsOnlyCheckbox.Value == 1
                app.lightsOnRoomsOnly = true;
            else
                app.lightsOnRoomsOnly = false;
            end
            RefreshButtonPushed(app);
        end

        % Close request function: HueDashboardv53UIFigure
        function HueDashboardv53UIFigureCloseRequest(app, event)

            stopTimer(app);

            delete(app)

        end

        % Button pushed function: AutoRefreshButton
        function AutoButtonPushed(app, event)

            if app.autoRefresh % Was ON
                app.AutoRefreshButton.BackgroundColor = 'black';
                app.AutoRefreshButton.FontColor = 'yellow';
                app.AutoRefreshButton.Text = 'Auto OFF';
                app.AutoRefreshButton.Tooltip = 'Details will NOT be automatically refreshed when a Motion is detected.';
                app.autoRefresh = false;
            else % Was OFF
                app.AutoRefreshButton.BackgroundColor = app.RefreshButton.BackgroundColor;
                app.AutoRefreshButton.FontColor = app.RefreshButton.FontColor;
                app.AutoRefreshButton.Text = 'Auto ON';
                app.AutoRefreshButton.Tooltip = 'Details will be automatically refreshed when a Motion is detected.';
                app.autoRefresh = true;
            end
        end

        % Value changed function: APIVersionDropDown
        function BridgeVersionChanged(app, event)
            app.vAPI = app.APIVersionDropDown.Value;
            restart(app);
        end

        % Value changed function: OfflineMode
        function offlineModeChanged(app, event)
            if app.OfflineMode.Value == 1
                app.offlineMode = true;
                app.DiagnosticsMsgTextArea.Value = 'Switched to Off-line Mode.';
                app.autoRefresh = false;
                app.AutoRefreshButton.Visible ="off";
                app.ResponseLimitSwitch.Visible ="off";
            else
                app.offlineMode = false;
                app.DiagnosticsMsgTextArea.Value = 'Switched back to On-line Mode.';
                app.autoRefresh = true;
                app.AutoRefreshButton.Visible ="on";
                app.ResponseLimitSwitch.Visible ="on";
            end
            restartDetails= append('Restarted: ', app.DiagnosticsMsgTextArea.Value);
            if ispc
                save([app.dataRepository '\hueAppDiagnostics'],"restartDetails");
            else
                save([app.dataRepository '/hueAppDiagnostics'],"restartDetails");
            end
            drawnow;
            pause(2);
            restart(app);
        end

        % Value changed function: ResponseLimitSwitch
        function ResponseLimitSwitchFlipped(app, event)
            if app.ResponseLimitSwitch.Value == "On"
                app.ResponseLimitSwitch.Tooltip = 'Response Limit ON.';
            else
                app.ResponseLimitSwitch.Tooltip = 'Response Limit OFF.';
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.HueDashboardv53UIFigure = uifigure('Visible', 'off');
            app.HueDashboardv53UIFigure.Name = 'HueDashboard (v5.3)';
            app.HueDashboardv53UIFigure.Icon = fullfile(pathToMLAPP, 'huelogo.png');
            app.HueDashboardv53UIFigure.CloseRequestFcn = createCallbackFcn(app, @HueDashboardv53UIFigureCloseRequest, true);
            app.HueDashboardv53UIFigure.KeyPressFcn = createCallbackFcn(app, @HueDashboardv53UIFigureKeyPress, true);
            app.ExitButton = uibutton(app.HueDashboardv53UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.PhilipsHueDashboardLabel = uilabel(app.HueDashboardv53UIFigure);
            app.PhilipsHueDashboardLabel.Text = 'Philips Hue Dashboard';
            app.RefreshButton = uibutton(app.HueDashboardv53UIFigure, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Tooltip = {'Refresh the display with latest Bridge data.'};
            app.RefreshButton.Text = 'Refresh';
            app.IPAddressLabel_3 = uilabel(app.HueDashboardv53UIFigure);
            app.IPAddressLabel_3.Text = 'API v.';
            app.IPAddressLabel = uilabel(app.HueDashboardv53UIFigure);
            app.IPAddressLabel.Text = 'IP Address';
            app.ZigbeeChannelLabel = uilabel(app.HueDashboardv53UIFigure);
            app.ZigbeeChannelLabel.Text = 'Zigbee Chan';
            app.LastFirmwareUpdateSoftwareVersionTextAreaLabel = uilabel(app.HueDashboardv53UIFigure);
            app.LastFirmwareUpdateSoftwareVersionTextAreaLabel.Text = 'Last  Firmware Update / Software Version';
            app.IPAddressLabel_4 = uilabel(app.HueDashboardv53UIFigure);
            app.IPAddressLabel_4.Text = 'Auto Update';
            app.PreviousStatusRefreshLabel = uilabel(app.HueDashboardv53UIFigure);
            app.PreviousStatusRefreshLabel.Text = {'Last Bridge'; 'Data Refresh'};
            app.ZigbeeChannelLabel_2 = uilabel(app.HueDashboardv53UIFigure);
            app.ZigbeeChannelLabel_2.Text = {'Refresh'; 'Response'};
            app.APIversion = uitextarea(app.HueDashboardv53UIFigure);
            app.APIversion.Editable = 'off';
            app.APIversion.Tooltip = {'Current Bridge API version.'};
            app.IPAddress = uitextarea(app.HueDashboardv53UIFigure);
            app.IPAddress.Editable = 'off';
            app.IPAddress.Tooltip = {'Current Bridge IP Address.'};
            app.ZigbeeChannel = uieditfield(app.HueDashboardv53UIFigure, 'numeric');
            app.ZigbeeChannel.Editable = 'off';
            app.ZigbeeChannel.Tooltip = {'The Zigbee Channel selected in the Bridge settings'};
            app.LastFirmwareUpdate = uitextarea(app.HueDashboardv53UIFigure);
            app.LastFirmwareUpdate.Editable = 'off';
            app.LastFirmwareUpdate.Tooltip = {'Time and version of the last Bridge Software update.'};
            app.AutoSWUpdate = uitextarea(app.HueDashboardv53UIFigure);
            app.AutoSWUpdate.Editable = 'off';
            app.AutoSWUpdate.Tooltip = {'Indicates whether the Bridge settings have Software Auto-Update enabled.'};
            app.LastBridgeDataRefreshTextArea = uitextarea(app.HueDashboardv53UIFigure);
            app.LastBridgeDataRefreshTextArea.Editable = 'off';
            app.LastBridgeDataRefreshTextArea.Tooltip = {'Timestamp of last Bridge Data Refresh.'};
            app.Response = uieditfield(app.HueDashboardv53UIFigure, 'numeric');
            app.Response.ValueDisplayFormat = '%.0f';
            app.Response.Editable = 'off';
            app.Response.Tooltip = {'Response time (seconds) for last Refresh.  When this exceeds the response time limit the app will restart to improve efficiency.'};
            app.IPAddressLabel_2 = uilabel(app.HueDashboardv53UIFigure);
            app.IPAddressLabel_2.Text = 'Bridge ID';
            app.BridgeID = uitextarea(app.HueDashboardv53UIFigure);
            app.BridgeID.Editable = 'off';
            app.BridgeID.Tooltip = {'The Bridge ID specified in the configuration. '};
            app.SWversion = uitextarea(app.HueDashboardv53UIFigure);
            app.SWversion.Editable = 'off';
            app.Label = uilabel(app.HueDashboardv53UIFigure);
            app.Label.Text = '/';
            app.SWUpDateAvail = uitextarea(app.HueDashboardv53UIFigure);
            app.SWUpDateAvail.Editable = 'off';
            app.SWUpDateAvail.Tooltip = {'Indicates whether the Bridge has pending Software Updates.'};
            app.SensorTemperaturesPanel = uipanel(app.HueDashboardv53UIFigure);
            app.SensorTemperaturesPanel.Title = 'Sensor Temperatures (max/min)';
            app.CMSensorsLabel = uilabel(app.SensorTemperaturesPanel);
            app.CMSensorsLabel.Text = 'Comfort      18-21';
            app.CMSensors = uieditfield(app.SensorTemperaturesPanel, 'numeric');
            app.CMSensors.Editable = 'off';
            app.CLSensors = uieditfield(app.SensorTemperaturesPanel, 'numeric');
            app.CLSensors.Editable = 'off';
            app.COSensors = uieditfield(app.SensorTemperaturesPanel, 'numeric');
            app.COSensors.Editable = 'off';
            app.WASensors = uieditfield(app.SensorTemperaturesPanel, 'numeric');
            app.WASensors.Editable = 'off';
            app.HOSensors = uieditfield(app.SensorTemperaturesPanel, 'numeric');
            app.HOSensors.Editable = 'off';
            app.HotLabel = uilabel(app.SensorTemperaturesPanel);
            app.HotLabel.Text = 'Hot      > 30';
            app.WarmLabel = uilabel(app.SensorTemperaturesPanel);
            app.WarmLabel.Text = 'Warm   > 21';
            app.CoolLabel = uilabel(app.SensorTemperaturesPanel);
            app.CoolLabel.Text = 'Cool     < 18';
            app.ColdLable = uilabel(app.SensorTemperaturesPanel);
            app.ColdLable.Text = 'Cold     < 10';
            app.FreezingLable = uilabel(app.SensorTemperaturesPanel);
            app.FreezingLable.Text = 'Freezing < 0';
            app.FRSensors = uieditfield(app.SensorTemperaturesPanel, 'numeric');
            app.FRSensors.Editable = 'off';
            app.SensorsandSwitchesLabel = uilabel(app.HueDashboardv53UIFigure);
            app.SensorsandSwitchesLabel.Tooltip = {'Temperatures in Celcius and Battery percentage remaining.'; 'Last Events pertain to triggered detections or programmed automations for Sensors and manual interactions with Switches/dimmers.'};
            app.SensorsandSwitchesLabel.Interpreter = 'html';
            app.SensorsandSwitchesLabel.Text = 'Sensors and Switches';
            app.TempsTable = uitable(app.HueDashboardv53UIFigure);
            app.TempsTable.ColumnName = {'Temp'; 'Batt'; 'Name (model)'; 'Last Event'};
            app.TempsTable.RowName = {};
            app.TempsTable.ColumnSortable = true;
            app.LightsOnLabel = uilabel(app.HueDashboardv53UIFigure);
            app.LightsOnLabel.Tooltip = {'TOGGLE switches lights between ON and OFF state.'; '*'; 'Click on a Group cell (Column 1) to Toggle a lights Group OR Toggle individual lights by clicking light-specific cells in Cols 2-7.  '; '*'; 'To apply brightness or colour changes to light Groups:'; '1) Type brightness percentage or colour character in Col 8 (Bri) or Col 9 (Colour), respectively - do NOT hit Enter or Tab.'; '2) Click on a Group cell in the SAME row as the above changes were made. '; '*'; 'To apply brightness or colour changes to ONE light:'; '1) Type brightness percentage or colour character in Col 8 (Bri) or Col 9 (Colour), respectively.'; '2) Hit Enter or Tab.'; '*'; 'Valid Colour values are:  r(ed), g(reen), b(lue) , y(ellow), w(hite), p(urple), o(range), c(yan), m(agenta).'};
            app.LightsOnLabel.Text = 'Lights';
            app.ToggleALLLightsButton = uibutton(app.HueDashboardv53UIFigure, 'push');
            app.ToggleALLLightsButton.ButtonPushedFcn = createCallbackFcn(app, @ToggleALLLightsButtonPushed, true);
            app.ToggleALLLightsButton.Text = {'Toggle ALL'; 'Lights'};
            app.AllLightsOff = uibutton(app.HueDashboardv53UIFigure, 'push');
            app.AllLightsOff.ButtonPushedFcn = createCallbackFcn(app, @AllLightsOffButtonPushed, true);
            app.AllLightsOff.Text = 'ALL OFF';
            app.AllLightsOn = uibutton(app.HueDashboardv53UIFigure, 'push');
            app.AllLightsOn.ButtonPushedFcn = createCallbackFcn(app, @AllLightsOnButtonPushed, true);
            app.AllLightsOn.Text = 'ALL ON';
            app.LightsTable = uitable(app.HueDashboardv53UIFigure);
            app.LightsTable.ColumnName = {'Group'; 'Type'; 'No'; 'Name'; 'Product Name'; 'Model'; 'On'; 'Bri'; 'Colour'};
            app.LightsTable.RowName = {};
            app.LightsTable.ColumnSortable = true;
            app.LightsTable.ColumnEditable = [false false false false false false false true true];
            app.LightsTable.CellEditCallback = createCallbackFcn(app, @LightsTableCellEdit, true);
            app.LightsTable.CellSelectionCallback = createCallbackFcn(app, @LightsTableCellSelection, true);
            app.LightsTable.SelectionChangedFcn = createCallbackFcn(app, @LightsTableSelectionChanged, true);
            app.LightsTable.Multiselect = 'off';
            app.LightsTable.Tooltip = {''};
            app.LightsTable.KeyPressFcn = createCallbackFcn(app, @LightsTableKeyPressed, true);
            app.RulesLabel_2 = uilabel(app.HueDashboardv53UIFigure);
            app.RulesLabel_2.Tooltip = {'Columns can be rearranged by dragging the column heading.'};
            app.RulesLabel_2.Text = 'Rules';
            app.NumberofRulesNOTtriggeredsincethelastBridgefirmwareupdateLabel = uilabel(app.HueDashboardv53UIFigure);
            app.NumberofRulesNOTtriggeredsincethelastBridgefirmwareupdateLabel.Text = 'Number of Rules NOT triggered since the last Bridge firmware update:';
            app.NeverTriggered = uieditfield(app.HueDashboardv53UIFigure, 'numeric');
            app.NeverTriggered.Editable = 'off';
            app.NeverTriggered.Tooltip = {'The number of Rules ''never'' triggered is based on the ''timestriggered'' value held on the Bridge.  This is reset when the Bridge software is updated.  Also note that multiple Rules are created for every event or condition set for each device.'};
            app.RulesTable = uitable(app.HueDashboardv53UIFigure);
            app.RulesTable.ColumnName = {'No'; 'Location'; 'Rule Name'; 'Created'; 'Last Trigger'; 'Trigs'; 'Status'; 'Action'; 'Target'; 'Tgt No'; 'Target Name'; 'Resource Link'};
            app.RulesTable.ColumnRearrangeable = 'on';
            app.RulesTable.RowName = {};
            app.RulesTable.ColumnSortable = true;
            app.inTimesTriggeredOrderLabel = uilabel(app.HueDashboardv53UIFigure);
            app.inTimesTriggeredOrderLabel.Text = '(in Times Triggered Order)';
            app.UnreachableLightsLabel = uilabel(app.HueDashboardv53UIFigure);
            app.UnreachableLightsLabel.Tooltip = {'Any unreachable lights will be listed here.'};
            app.UnreachableLightsLabel.Text = 'Unreachable Lights';
            app.TableLights = uitable(app.HueDashboardv53UIFigure);
            app.TableLights.ColumnName = {'ID'; 'Light'};
            app.TableLights.RowName = {};
            app.TableLights.ColumnSortable = true;
            app.TableSensors = uitable(app.HueDashboardv53UIFigure);
            app.TableSensors.ColumnName = {'%'; 'Model'; 'Name'};
            app.TableSensors.RowName = {};
            app.TableSensors.ColumnSortable = true;
            app.DiagnosticsTextAreaLabel = uilabel(app.HueDashboardv53UIFigure);
            app.DiagnosticsTextAreaLabel.Text = 'Diagnostics';
            app.DiagnosticsMsgTextArea = uitextarea(app.HueDashboardv53UIFigure);
            app.DiagnosticsMsgTextArea.Editable = 'off';
            app.DiagnosticsTimeTextArea = uitextarea(app.HueDashboardv53UIFigure);
            app.DiagnosticsTimeTextArea.Editable = 'off';
            app.BridgeConfigurationandUsagePanel = uipanel(app.HueDashboardv53UIFigure);
            app.BridgeConfigurationandUsagePanel.Title = 'Bridge Configuration and Usage';
            app.InstalledLabel = uilabel(app.BridgeConfigurationandUsagePanel);
            app.InstalledLabel.Text = 'Installed';
            app.BridgeLimitLabel = uilabel(app.BridgeConfigurationandUsagePanel);
            app.BridgeLimitLabel.Text = 'Bridge Limit';
            app.UsedLabel = uilabel(app.BridgeConfigurationandUsagePanel);
            app.UsedLabel.Text = 'Used %';
            app.OnActiveLabel = uilabel(app.BridgeConfigurationandUsagePanel);
            app.OnActiveLabel.Text = 'On / Active';
            app.LightsLabel = uilabel(app.BridgeConfigurationandUsagePanel);
            app.LightsLabel.Text = 'Lights';
            app.AVLights = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.AVLights.Editable = 'off';
            app.BRLights = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.BRLights.Editable = 'off';
            app.PCLights = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.PCLights.ValueDisplayFormat = '%5.2f';
            app.PCLights.Editable = 'off';
            app.ONLights = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.ONLights.Editable = 'off';
            app.RulesLabel = uilabel(app.BridgeConfigurationandUsagePanel);
            app.RulesLabel.Text = 'Rules';
            app.AVRules = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.AVRules.Editable = 'off';
            app.BRRules = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.BRRules.Editable = 'off';
            app.PCRules = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.PCRules.ValueDisplayFormat = '%5.2f';
            app.PCRules.Editable = 'off';
            app.ONRules = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.ONRules.Editable = 'off';
            app.GroupsLabel = uilabel(app.BridgeConfigurationandUsagePanel);
            app.GroupsLabel.Text = 'Light Groups';
            app.AVGroups = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.AVGroups.Editable = 'off';
            app.BRGroups = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.BRGroups.Editable = 'off';
            app.PCGroups = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.PCGroups.ValueDisplayFormat = '%5.2f';
            app.PCGroups.Editable = 'off';
            app.OnGroups = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.OnGroups.Editable = 'off';
            app.ScenesLabel = uilabel(app.BridgeConfigurationandUsagePanel);
            app.ScenesLabel.Text = 'Scenes';
            app.AVScenes = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.AVScenes.Editable = 'off';
            app.BRScenes = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.BRScenes.Editable = 'off';
            app.PCScenes = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.PCScenes.ValueDisplayFormat = '%5.2f';
            app.PCScenes.Editable = 'off';
            app.SchedulesLabel = uilabel(app.BridgeConfigurationandUsagePanel);
            app.SchedulesLabel.Text = 'Schedules';
            app.AVSchedules = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.AVSchedules.Editable = 'off';
            app.BRSchedules = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.BRSchedules.Editable = 'off';
            app.PCSchedules = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.PCSchedules.ValueDisplayFormat = '%5.2f';
            app.PCSchedules.Editable = 'off';
            app.ONSchedules = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.ONSchedules.Editable = 'off';
            app.ResourceLinksLabel = uilabel(app.BridgeConfigurationandUsagePanel);
            app.ResourceLinksLabel.Text = 'Resource Links';
            app.AVResourceLinks = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.AVResourceLinks.Editable = 'off';
            app.BRResourceLinks = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.BRResourceLinks.Editable = 'off';
            app.PCResourceLinks = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.PCResourceLinks.ValueDisplayFormat = '%5.2f';
            app.PCResourceLinks.Editable = 'off';
            app.SensorsLabel = uilabel(app.BridgeConfigurationandUsagePanel);
            app.SensorsLabel.Text = 'Sensors';
            app.AVSensors = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.AVSensors.Editable = 'off';
            app.BRSensors = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.BRSensors.Editable = 'off';
            app.PCSensors = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.PCSensors.ValueDisplayFormat = '%5.2f';
            app.PCSensors.Editable = 'off';
            app.MotionSensors = uilabel(app.BridgeConfigurationandUsagePanel);
            app.MotionSensors.Text = '99';
            app.MotionSensorsLabel = uilabel(app.BridgeConfigurationandUsagePanel);
            app.MotionSensorsLabel.Text = 'Motion Sensors ';
            app.DimmerSwitches = uilabel(app.BridgeConfigurationandUsagePanel);
            app.DimmerSwitches.Text = '99';
            app.SwitchesLabel = uilabel(app.BridgeConfigurationandUsagePanel);
            app.SwitchesLabel.Text = 'Switches';
            app.devicesLabel = uilabel(app.BridgeConfigurationandUsagePanel);
            app.devicesLabel.Text = 'devices:';
            app.TotalDevices = uilabel(app.BridgeConfigurationandUsagePanel);
            app.TotalDevices.Text = '88';
            app.InLabel = uilabel(app.BridgeConfigurationandUsagePanel);
            app.InLabel.Text = 'In';
            app.onLabel = uilabel(app.HueDashboardv53UIFigure);
            app.onLabel.Text = 'on';
            app.WarningsPanel = uipanel(app.HueDashboardv53UIFigure);
            app.WarningsPanel.Title = 'Warnings';
            app.UnreachableLightsHdr = uilabel(app.WarningsPanel);
            app.UnreachableLightsHdr.Tooltip = {'Number of unreachable Lights.  Often temporary.'};
            app.UnreachableLightsHdr.Text = 'Unreachable Lights';
            app.LowBatteryDevicesHdr = uilabel(app.WarningsPanel);
            app.LowBatteryDevicesHdr.Tooltip = {'Number of devices with a battery level below 30%.'};
            app.LowBatteryDevicesHdr.Text = 'Low Battery Devices';
            app.HTMLLowBatDevs = uihtml(app.WarningsPanel);
            app.HTMLLowBatDevs.Tooltip = {'Number of devices with a battery level below 30%.'};
            app.HTMLUnreachableLights = uihtml(app.WarningsPanel);
            app.HTMLUnreachableLights.Tooltip = {'Number of unreachable Lights.  Often temporary.'};
            app.HTMLLowBatMsg = uihtml(app.HueDashboardv53UIFigure);
            app.HTMLTempTrend = uihtml(app.HueDashboardv53UIFigure);
            app.HTMLLightsOn = uihtml(app.HueDashboardv53UIFigure);
            app.HTMLLastMotion = uihtml(app.HueDashboardv53UIFigure);
            app.ResponseLimitSwitch = uiswitch(app.HueDashboardv53UIFigure, 'rocker');
            app.ResponseLimitSwitch.ValueChangedFcn = createCallbackFcn(app, @ResponseLimitSwitchFlipped, true);
            app.ResponseLimitSwitch.Tooltip = {'Enable auto-restart if Response Limit is exceeded.  Response Limit is calculated as Startup time * 1.25.'};
            app.HueLogo = uipanel(app.HueDashboardv53UIFigure);
            app.inLabel = uilabel(app.HueDashboardv53UIFigure);
            app.inLabel.Text = 'in';
            app.RoomsLabel = uilabel(app.HueDashboardv53UIFigure);
            app.RoomsLabel.Text = 'Rooms';
            app.noRoomsLightsOn = uilabel(app.HueDashboardv53UIFigure);
            app.noRoomsLightsOn.Text = '99';
            app.lightsOnRoomsOnlyCheckbox = uicheckbox(app.HueDashboardv53UIFigure);
            app.lightsOnRoomsOnlyCheckbox.ValueChangedFcn = createCallbackFcn(app, @lightOnRoomsOnly, true);
            app.lightsOnRoomsOnlyCheckbox.Tooltip = {'Show Only Rooms with Lights ON in the Grid.'};
            app.lightsOnRoomsOnlyCheckbox.Text = '';
            app.AutoRefreshButton = uibutton(app.HueDashboardv53UIFigure, 'push');
            app.AutoRefreshButton.ButtonPushedFcn = createCallbackFcn(app, @AutoButtonPushed, true);
            app.AutoRefreshButton.Tooltip = {'Auto-refresh upon Motion detection.  Checks at 15 second intervals.  Ignores consecutive movements in the same room.'};
            app.AutoRefreshButton.Text = 'Auto ON';
            app.APIVersionDropDownLabel = uilabel(app.HueDashboardv53UIFigure);
            app.APIVersionDropDownLabel.Text = 'API Version';
            app.APIVersionDropDown = uidropdown(app.HueDashboardv53UIFigure);
            app.APIVersionDropDown.Items = {'v1', 'v2'};
            app.APIVersionDropDown.ItemsData = [1 2];
            app.APIVersionDropDown.ValueChangedFcn = createCallbackFcn(app, @BridgeVersionChanged, true);
            app.APIVersionDropDown.Value = 1;
            app.OfflineMode = uicheckbox(app.HueDashboardv53UIFigure);
            app.OfflineMode.ValueChangedFcn = createCallbackFcn(app, @offlineModeChanged, true);
            app.OfflineMode.Tooltip = {'Switch to Offline Mode. (For testing and data analysis.)'};
            app.OfflineMode.Text = '';
            app.ContextMenu = uicontextmenu(app.HueDashboardv53UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            % Show the figure after all components are created
            app.HueDashboardv53UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HueDashboard

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HueDashboardv53UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HueDashboardv53UIFigure)
        end
    end
end